classdef NikonTiE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SavefiletoPanel                 matlab.ui.container.Panel
        ChooseButton                    matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        FolderpathEditFieldLabel        matlab.ui.control.Label
        FolderpathEditField             matlab.ui.control.EditField
        CameraPanel                     matlab.ui.container.Panel
        StreamLampLabel                 matlab.ui.control.Label
        StreamLamp                      matlab.ui.control.Lamp
        PreviewSwitch                   matlab.ui.control.Switch
        CamConnectButton                matlab.ui.control.StateButton
        ExposuretimemsecEditFieldLabel  matlab.ui.control.Label
        ExposuretimemsecEditField       matlab.ui.control.NumericEditField
        GainmodeDropDownLabel           matlab.ui.control.Label
        GainmodeDropDown                matlab.ui.control.DropDown
        TriggermodeDropDownLabel        matlab.ui.control.Label
        TriggermodeDropDown             matlab.ui.control.DropDown
        SpuriousNoiseFilterCheckBox     matlab.ui.control.CheckBox
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        FramepersecondHzEditFieldLabel  matlab.ui.control.Label
        FramepersecondHzEditField       matlab.ui.control.NumericEditField
        ReadoutmodeDropDownLabel        matlab.ui.control.Label
        ReadoutmodeDropDown             matlab.ui.control.DropDown
        CenterCheckBox                  matlab.ui.control.CheckBox
        WidthEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        UpdateButton                    matlab.ui.control.Button
        OverlapCheckBox                 matlab.ui.control.CheckBox
        AcquisitionsettingPanel         matlab.ui.container.Panel
        StartAcquiButton                matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        StarvationTab                   matlab.ui.container.Tab
        nFramesEditFieldLabel           matlab.ui.control.Label
        nFramesEditField                matlab.ui.control.NumericEditField
        nLoopsEditFieldLabel            matlab.ui.control.Label
        nLoopsEditField                 matlab.ui.control.NumericEditField
        nTimerDropDown                  matlab.ui.control.DropDown
        nTimersEditFieldLabel           matlab.ui.control.Label
        nTimersEditField                matlab.ui.control.NumericEditField
        CorrectXYCheckBox               matlab.ui.control.CheckBox
        CorrectZCheckBox                matlab.ui.control.CheckBox
        nFluosEditFieldLabel            matlab.ui.control.Label
        nFluosEditField                 matlab.ui.control.EditField
        nFluoDropDown                   matlab.ui.control.DropDown
        MagEditFieldLabel               matlab.ui.control.Label
        MagEditField                    matlab.ui.control.NumericEditField
        ScanXYTab                       matlab.ui.container.Tab
        Start2Button                    matlab.ui.control.Button
        ThispartistestingLabel          matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        HistoryPanel                    matlab.ui.container.Panel
        HistoryTextArea                 matlab.ui.control.TextArea
        LightSourcePanel                matlab.ui.container.Panel
        WhitelightPanel                 matlab.ui.container.Panel
        BFSwitch                        matlab.ui.control.RockerSwitch
        BFLamp                          matlab.ui.control.Lamp
        BFConnectButton                 matlab.ui.control.StateButton
        daqDevEditField                 matlab.ui.control.EditField
        daqAOEditField                  matlab.ui.control.EditField
        DAQLabel                        matlab.ui.control.Label
        CoolLEDPanel                    matlab.ui.container.Panel
        pE_ALamp                        matlab.ui.control.Lamp
        pE_BLamp                        matlab.ui.control.Lamp
        pE_CLamp                        matlab.ui.control.Lamp
        pE_DLamp                        matlab.ui.control.Lamp
        pEConnectButton                 matlab.ui.control.StateButton
        pE_ACheckBox                    matlab.ui.control.CheckBox
        pE_BCheckBox                    matlab.ui.control.CheckBox
        pE_CCheckBox                    matlab.ui.control.CheckBox
        pE_DCheckBox                    matlab.ui.control.CheckBox
        pE_ADropDown                    matlab.ui.control.DropDown
        pE_BDropDown                    matlab.ui.control.DropDown
        pE_CDropDown                    matlab.ui.control.DropDown
        pE_DDropDown                    matlab.ui.control.DropDown
        pE_ASpinner                     matlab.ui.control.Spinner
        pE_BSpinner                     matlab.ui.control.Spinner
        pE_CSpinner                     matlab.ui.control.Spinner
        pE_DSpinner                     matlab.ui.control.Spinner
        pESwitch                        matlab.ui.control.ToggleSwitch
        ExnmLabel                       matlab.ui.control.Label
        PowerLabel                      matlab.ui.control.Label
        UseLabel                        matlab.ui.control.Label
        pE_KeepUpdateCheckBox           matlab.ui.control.CheckBox
        NikonTiEPanel                   matlab.ui.container.Panel
        MotorizedStagePanel             matlab.ui.container.Panel
        StageConnectButton              matlab.ui.control.StateButton
        XumLabel                        matlab.ui.control.Label
        XumEditField                    matlab.ui.control.NumericEditField
        YumEditFieldLabel               matlab.ui.control.Label
        YumEditField                    matlab.ui.control.NumericEditField
        ZumEditFieldLabel               matlab.ui.control.Label
        ZumEditField                    matlab.ui.control.NumericEditField
        PFSEditFieldLabel               matlab.ui.control.Label
        PFSEditField                    matlab.ui.control.NumericEditField
        AddButton                       matlab.ui.control.Button
        PFSLampLabel                    matlab.ui.control.Label
        PFSLamp                         matlab.ui.control.Lamp
        PFSSwitch                       matlab.ui.control.Switch
        ListButton                      matlab.ui.control.Button
        roiCountEditField               matlab.ui.control.NumericEditField
        XYZUpdateCheckBox               matlab.ui.control.CheckBox
        LightPathPanel                  matlab.ui.container.Panel
        FluoCubeButtonGroup             matlab.ui.container.ButtonGroup
        FLC_Button_1                    matlab.ui.control.ToggleButton
        FLC_Button_2                    matlab.ui.control.ToggleButton
        FLC_Button_3                    matlab.ui.control.ToggleButton
        FLC_Button_4                    matlab.ui.control.ToggleButton
        FLC_Button_5                    matlab.ui.control.ToggleButton
        FLC_Button_6                    matlab.ui.control.ToggleButton
        ViewButtonGroup                 matlab.ui.container.ButtonGroup
        EyeButton_6                     matlab.ui.control.RadioButton
        L100Button2                     matlab.ui.control.RadioButton
        R100Button3                     matlab.ui.control.RadioButton
    end

    properties (Access = public)
        hndl;
        mmc;
        dq;
        hFig;
        hImage;
        schedule;
        fluo_schedule;
        fluo_ex_idx
        schedule_timer;
        listing_pos = [];
        database0 = [];
        ismmc = false;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            selpath = uigetdir('F:\');
            History = flip(app.HistoryTextArea.Value,1);
            
            if ischar(selpath)
                app.FolderpathEditField.Value = selpath;
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',selpath,'" as the current working folder.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            selpath = app.FolderpathEditField.Value;
            History = flip(app.HistoryTextArea.Value,1);
            
            if ~isempty(selpath)
                winopen(selpath);
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Open the current working folder.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: BFConnectButton
        function BFConnectButtonValueChanged(app, event)
            app.BFConnectButton.Enable=false;
            pause(0.1)
            value = app.BFConnectButton.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if (value==1)
                app.BFSwitch.Enable = true;
                app.BFConnectButton.Text = 'Disconnect';
                app.BFConnectButton.BackgroundColor = [1,0,0];
                
                % create ni daq session and output channel
                app.dq = daq("ni");
                addoutput(app.dq, "Dev2", "ao0", "Voltage");
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Connect NI-DAQ.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                app.BFSwitch.Enable = false;
                app.BFConnectButton.Text = 'Connect';
                app.BFConnectButton.BackgroundColor = [0,1,0];
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Disconnect NI-DAQ.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
            app.BFConnectButton.Enable=true;
        end

        % Value changed function: BFSwitch
        function BFSwitchValueChanged(app, event)
            value = app.BFSwitch.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if strcmp(value,'On')
                app.BFLamp.Color = [1.00,1.00,0.00];
                
                % write command to daq
                write(app.dq,5);
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn on BF.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                app.BFLamp.Color = [0.9,0.9,0.9];
                
                % write command to daq
                write(app.dq,0);
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn off BF.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: CamConnectButton
        function CamConnectButtonValueChanged(app, event)
            app.CamConnectButton.Enable=false;
            pause(0.1)
            value = app.CamConnectButton.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if (value==1)
                app.CamConnectButton.Text = 'Disconnect';
                app.CamConnectButton.BackgroundColor = [1,0,0];
                app.PreviewSwitch.Enable = true;
                app.TemperatureCEditField.Enable = true;
                app.FramepersecondHzEditField.Enable = true;
                app.ExposuretimemsecEditField.Enable = true;
                app.ExposuretimemsecEditField.Value = 30;
                app.GainmodeDropDown.Enable = true;
                app.GainmodeDropDown.Value = '12-bit (high well capacity)';
                app.TriggermodeDropDown.Enable = true;
                app.TriggermodeDropDown.Value = 'Internal';
                app.ReadoutmodeDropDown.Enable = true;
                app.ReadoutmodeDropDown.Value = '10 MHz';
                app.WidthEditField.Enable = true;
                app.WidthEditField.Value = 2048;
                app.HeightEditField.Enable = true;
                app.HeightEditField.Value = 2048;
                app.SpuriousNoiseFilterCheckBox.Enable = true;
                app.SpuriousNoiseFilterCheckBox.Value = 1;
                app.OverlapCheckBox.Enable = true;
                app.OverlapCheckBox.Value = 1;
                app.CenterCheckBox.Enable = true;
                app.CenterCheckBox.Value = 1;
                
                % create camera session
                AT_InitialiseLibrary();
                [~,app.hndl] = AT_Open(0);
                
                % set cooling and get current temperature
                AT_SetBool(app.hndl,'SensorCooling',1);                                         % turn on/off cooling
                %                 [~,cooling] = AT_GetBool(app.hndl,'SensorCooling');                          	% get cooling status
                %                 [~,currentIndex] = AT_GetEnumIndex(app.hndl,'TemperatureStatus');            	% get temperature status [0-cooler off, 1-stable, 2-cooling, 3-drift, 4-not stable, 5-Fault]
                [~,SensorTemp] = AT_GetFloat(app.hndl,'SensorTemperature');                     % get sensor temperaure
                app.TemperatureCEditField.Value = SensorTemp;
                
                % get camera current setting
                % Overlap
                [~,bool] = AT_GetBool(app.hndl,'Overlap');
                if bool==1
                    app.OverlapCheckBox.Value = true;
                else
                    app.OverlapCheckBox.Value = false;
                end
                
                % SpuriousNoiseFilter
                [~,bool] = AT_GetBool(app.hndl,'SpuriousNoiseFilter');
                if bool==1
                    app.SpuriousNoiseFilterCheckBox.Value = true;
                else
                    app.SpuriousNoiseFilterCheckBox.Value = false;
                end
                
                % Center ROI
                [~,bool] = AT_GetBool(app.hndl,'VerticallyCentreAOI');
                if bool==1
                    app.CenterCheckBox.Value = true;
                else
                    app.CenterCheckBox.Value = false;
                end
                
                % trigger mode
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'TriggerMode');
                switch currentIndex
                    case 0
                        app.TriggermodeDropDown.Value = 'Internal';
                    case 1
                        app.TriggermodeDropDown.Value = 'External Level Transition';
                    case 2
                        app.TriggermodeDropDown.Value = 'External Start';
                    case 3
                        app.TriggermodeDropDown.Value = 'External Exposure';
                    case 4
                        app.TriggermodeDropDown.Value = 'Software';
                    case 5
                        app.TriggermodeDropDown.Value = 'Advanced';
                    case 6
                        app.TriggermodeDropDown.Value = 'External';
                end
                
                % readout
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'PixelReadoutRate');                 % 0-10 MHz, 1-100 MHz, 2-200 MHz, 3-270 MHz
                switch currentIndex
                    case 0
                        app.ReadoutmodeDropDown.Value = '10 MHz';
                    case 1
                        app.ReadoutmodeDropDown.Value = '100 MHz';
                    case 2
                        app.ReadoutmodeDropDown.Value = '200 MHz';
                    case 3
                        app.ReadoutmodeDropDown.Value = '270 MHz';
                end
                %                 [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SensorReadoutMode');                % 0-Top Down Simultaneous, 1-Centre Out Simultaneous, 2-Outside In Simultaneous, 3-Bottom Up Simultaneous, 4-Top Down Sequential, 5-Bottom Up Sequential
                
                % gain mode
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');          % 0-12-bit (high well capacity), 1-12-bit (low noise), 2-16-bit (low noise & high well capacity)
                switch currentIndex
                    case 0
                        app.GainmodeDropDown.Value = '12-bit (high well capacity)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono12');
                    case 1
                        app.GainmodeDropDown.Value = '12-bit (low noise)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono12');
                    case 2
                        app.GainmodeDropDown.Value = '16-bit (low noise & high well capacity)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono16');
                end
                
                
                % ROI
                %                 [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
                [~,height] = AT_GetInt(app.hndl,'AOIHeight');
                app.HeightEditField.Value = height;
                [~,width] = AT_GetInt(app.hndl,'AOIWidth');
                app.WidthEditField.Value = width;
                %                 [~,left] = AT_GetInt(app.hndl,'AOILeft');
                %                 [~,stride] = AT_GetInt(app.hndl,'AOIStride');
                
                % exposure time
                ExposureTimeInSecond = app.ExposuretimemsecEditField.Value/1000;
                AT_SetFloat(app.hndl,'ExposureTime', ExposureTimeInSecond);
                pause(0.1)
                [~,ExposureTimeInSecond] = AT_GetFloat(app.hndl,'ExposureTime');
                app.ExposuretimemsecEditField.Value = ExposureTimeInSecond*1000;
                
                % fps
                [~,frameRate_Max] = AT_GetFloatMax(app.hndl,'FrameRate');
                AT_SetFloat(app.hndl,'FrameRate',frameRate_Max);
                pause(0.1)
                [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
                app.FramepersecondHzEditField.Value=frameRate;
                
                % open a new window
                app.hFig = figure('CloseRequestFcn','');
                width = app.WidthEditField.Value;
                height = app.HeightEditField.Value;
                app.hImage = imshow(zeros(height,width),'Border','Tight');
                set(gca,'DataAspectRatio',[1,1,1],'PlotBoxAspectRatio',[1,1,1])
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Connect Andor Zyla 5.5.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                app.PreviewSwitch.Enable = false;
                app.CamConnectButton.Text = 'Connect';
                app.CamConnectButton.BackgroundColor = [0,1,0];
                app.PreviewSwitch.Enable = false;
                app.UpdateButton.Enable = false;
                app.TemperatureCEditField.Enable = false;
                app.FramepersecondHzEditField.Enable = false;
                app.ExposuretimemsecEditField.Enable = false;
                app.GainmodeDropDown.Enable = false;
                app.TriggermodeDropDown.Enable = false;
                app.ReadoutmodeDropDown.Enable = false;
                app.WidthEditField.Enable = false;
                app.HeightEditField.Enable = false;
                app.SpuriousNoiseFilterCheckBox.Enable = false;
                app.OverlapCheckBox.Enable = false;
                app.CenterCheckBox.Enable = false;
                delete(app.hFig)
                
                % turn on/off cooling
                AT_SetBool(app.hndl,'SensorCooling',0);
                
                % close and clear camera session
                AT_Close(app.hndl);
                AT_FinaliseLibrary();
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Disconnect Andor Zyla 5.5.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
            app.CamConnectButton.Enable=true;
        end

        % Value changed function: PreviewSwitch
        function PreviewSwitchValueChanged(app, event)
            value = app.PreviewSwitch.Value;
            subvalue = app.UpdateButton.Enable;
            History = flip(app.HistoryTextArea.Value,1);
            if strcmp(value,'On') && strcmp(subvalue,'off')
                app.StreamLamp.Color = [0,1,0];
                app.CamConnectButton.Enable = false;
                app.StageConnectButton.Enable = false;
                app.pEConnectButton.Enable=false;
                app.BFConnectButton.Enable = false;
                app.ExposuretimemsecEditField.Enable = false;
                app.GainmodeDropDown.Enable = false;
                app.TriggermodeDropDown.Enable = false;
                app.ReadoutmodeDropDown.Enable = false;
                app.WidthEditField.Enable = false;
                app.HeightEditField.Enable = false;
                app.SpuriousNoiseFilterCheckBox.Enable = false;
                app.OverlapCheckBox.Enable = false;
                app.CenterCheckBox.Enable = false;
                app.XYZUpdateCheckBox.Enable = false;
                app.pE_KeepUpdateCheckBox.Enable=false;
                
                if (app.StageConnectButton.Value==1)
                    app.XYZUpdateCheckBox.Value = true;
                end
                
                if (app.pEConnectButton.Value==1)
                    app.pE_KeepUpdateCheckBox.Value = true;
                end
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Preview...'));
                app.HistoryTextArea.Value = flip(History,1);
                
                % check whether turn on white light
                if strcmp(app.BFSwitch.Value,'Off') && (app.BFConnectButton.Value==1)
                    app.BFSwitch.Value = 'On';
                    app.BFLamp.Color = [1.00,1.00,0.00];
                    
                    % write command to daq
                    write(app.dq,5);
                    
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn on the BF.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
                
                % camera setting
                [~,SensorTemp] = AT_GetFloat(app.hndl,'SensorTemperature');                     % get sensor temperaure
                app.TemperatureCEditField.Value = SensorTemp;
                
                AT_SetEnumString(app.hndl,'CycleMode','Continuous');
                pause(0.1)
                
                AT_SetEnumString(app.hndl,'TriggerMode','Software');
                pause(0.1)
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'TriggerMode');
                switch currentIndex
                    case 0
                        app.TriggermodeDropDown.Value = 'Internal';
                    case 1
                        app.TriggermodeDropDown.Value = 'External Level Transition';
                    case 2
                        app.TriggermodeDropDown.Value = 'External Start';
                    case 3
                        app.TriggermodeDropDown.Value = 'External Exposure';
                    case 4
                        app.TriggermodeDropDown.Value = 'Software';
                    case 5
                        app.TriggermodeDropDown.Value = 'Advanced';
                    case 6
                        app.TriggermodeDropDown.Value = 'External';
                end
                
                value = app.GainmodeDropDown.Value;
                AT_SetEnumString(app.hndl,'SimplePreAmpGainControl',value);
                pause(0.1)
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');          % 0-12-bit (high well capacity), 1-12-bit (low noise), 2-16-bit (low noise & high well capacity)
                switch currentIndex
                    case 0
                        app.GainmodeDropDown.Value = '12-bit (high well capacity)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono12');
                    case 1
                        app.GainmodeDropDown.Value = '12-bit (low noise)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono12');
                    case 2
                        app.GainmodeDropDown.Value = '16-bit (low noise & high well capacity)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono16');
                end
                pause(0.1)
                
                [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
                app.FramepersecondHzEditField.Value = frameRate;
                
                % image flow
                width = app.WidthEditField.Value;
                height = app.HeightEditField.Value;
                [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
                [~,stride] = AT_GetInt(app.hndl,'AOIStride');
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');
                AT_Command(app.hndl,'AcquisitionStart');
                while (1)
                    AT_QueueBuffer(app.hndl,imagesize);
                    AT_Command(app.hndl,'SoftwareTrigger');
                    [~,buf] = AT_WaitBuffer(app.hndl,1000);
                    if (currentIndex<2)
                        [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                    else
                        [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                    end
                    set(app.hImage,'CData',flip(buf2.',2))
                    if min(buf2(:))~=max(buf2(:))
                        set(gca, 'CLim',[min(buf2(:)),max(buf2(:))])
                    end
                    drawnow
                    
                    % update stage information
                    if (app.StageConnectButton.Value==1)
                        x = app.mmc.getXPosition();
                        y = app.mmc.getYPosition();
                        z = app.mmc.getPosition();
                        pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
                        if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                            app.PFSLamp.Color = [0,1,0];
                            app.PFSSwitch.Value = 'On';
                        else
                            app.PFSLamp.Color = [0.9,0.9,0.9];
                            app.PFSSwitch.Value = 'Off';
                        end
                        app.XumEditField.Value = x;
                        app.YumEditField.Value = y;
                        app.ZumEditField.Value = z;
                        app.PFSEditField.Value = pfs_z;
                        
                        FLC_idx=str2double(app.mmc.getProperty('TIFilterBlock1','State'));
                        switch FLC_idx
                            case 1
                                app.FLC_Button_1.Value=1;
                            case 2
                                app.FLC_Button_2.Value=1;
                            case 3
                                app.FLC_Button_3.Value=1;
                            case 4
                                app.FLC_Button_4.Value=1;
                            case 5
                                app.FLC_Button_5.Value=1;
                            case 0
                                app.FLC_Button_6.Value=1;
                        end
                        
                        view_port=str2double(app.mmc.getProperty('TILightPath','State'));
                        switch view_port
                            case 0
                                app.EyeButton_6.Value=1;
                            case 1
                                app.L100Button2.Value=1;
                            case 2
                                app.R100Button3.Value=1;
                        end
                    end


                    % updating pE4000 information
                    if (app.pE_KeepUpdateCheckBox.Value==1)
                        pE_state=str2double(app.mmc.getProperty('pE4000','Global State'));
                        if pE_state==1
                            app.pESwitch.Value='On';
                        else
                            app.pESwitch.Value='Off';
                        end
                        
                        pE_A_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionA'));
                        if pE_A_selectTF==1
                            app.pE_ACheckBox.Value=1;
                            app.pE_ALamp.Color=[0.72,0.27,1.00];
                        else
                            app.pE_ACheckBox.Value=0;
                            app.pE_ALamp.Color=[0.90,0.90,0.90];
                        end
                        pE_A_wavelength=app.mmc.getProperty('pE4000','ChannelA');
                        if (sum(str2double(app.pE_ADropDown.Items)==str2double(pE_A_wavelength))==1)
                            app.pE_ADropDown.Value=pE_A_wavelength;
                            pE_A_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityA'));
                            app.pE_ASpinner.Value=pE_A_Intensity;
                        end
                        
                        pE_B_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionB'));
                        if pE_B_selectTF==1
                            app.pE_BCheckBox.Value=1;
                            app.pE_BLamp.Color=[0.07,0.62,1.00];
                        else
                            app.pE_BCheckBox.Value=0;
                            app.pE_BLamp.Color=[0.90,0.90,0.90];
                        end
                        pE_B_wavelength=app.mmc.getProperty('pE4000','ChannelB');
                        if (sum(str2double(app.pE_BDropDown.Items)==str2double(pE_B_wavelength))==1)
                            app.pE_BDropDown.Value=pE_B_wavelength;
                            pE_B_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityB'));
                            app.pE_BSpinner.Value=pE_B_Intensity;
                        end
                        
                        pE_C_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionC'));
                        if pE_C_selectTF==1
                            app.pE_CCheckBox.Value=1;
                            app.pE_CLamp.Color=[0.00,1.00,0.00];
                        else
                            app.pE_CCheckBox.Value=0;
                            app.pE_CLamp.Color=[0.90,0.90,0.90];
                        end
                        pE_C_wavelength=app.mmc.getProperty('pE4000','ChannelC');
                        if (sum(str2double(app.pE_CDropDown.Items)==str2double(pE_C_wavelength))==1)
                            app.pE_CDropDown.Value=pE_C_wavelength;
                            pE_C_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityC'));
                            app.pE_CSpinner.Value=pE_C_Intensity;
                        end
                        
                        pE_D_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionD'));
                        if pE_D_selectTF==1
                            app.pE_DCheckBox.Value=1;
                            app.pE_DLamp.Color=[1.00,0.00,0.00];
                        else
                            app.pE_DCheckBox.Value=0;
                            app.pE_DLamp.Color=[0.90,0.90,0.90];
                        end
                        pE_D_wavelength=app.mmc.getProperty('pE4000','ChannelD');
                        if (sum(str2double(app.pE_DDropDown.Items)==str2double(pE_D_wavelength))==1)
                            app.pE_DDropDown.Value=pE_D_wavelength;
                            pE_D_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityD'));
                            app.pE_DSpinner.Value=pE_D_Intensity;
                        end
                        
                        
                        % update nFluos
                        nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
                        app.nFluosEditField.Value=num2str(nFluos);
                        app.nFluoDropDown.Items = {};
                        app.fluo_schedule = [];
                        if (nFluos>0)
                            app.nFluoDropDown.Visible = true;
                            Items{1} = 'Choose...';
                            for i = 2:nFluos+1
                                Items{i} = strcat('Fluo-',num2str(i-1));
                                app.fluo_schedule(i-1,:) = [0,0,1];
                            end
                            app.nFluoDropDown.Items = Items;
                        else
                            app.nFluoDropDown.Visible = false;
                        end
                    end
                    
                    if ~strcmp(app.PreviewSwitch.Value,'On')
                        AT_Command(app.hndl,'AcquisitionStop');
                        pause(0.1)
                        AT_Flush(app.hndl);
                        pause(0.1)
                        break;
                    end
                end
            else
                app.StreamLamp.Color = [0.9,0.9,0.9];
                app.CamConnectButton.Enable = true;
                app.StageConnectButton.Enable = true;
                app.pEConnectButton.Enable=true;
                app.BFConnectButton.Enable = true;
                app.ExposuretimemsecEditField.Enable = true;
                app.GainmodeDropDown.Enable = true;
                app.TriggermodeDropDown.Enable = true;
                app.ReadoutmodeDropDown.Enable = true;
                app.WidthEditField.Enable = true;
                app.HeightEditField.Enable = true;
                app.SpuriousNoiseFilterCheckBox.Enable = true;
                app.OverlapCheckBox.Enable = true;
                app.CenterCheckBox.Enable = true;
                app.XYZUpdateCheckBox.Enable = true;
                app.XYZUpdateCheckBox.Value = false;
                app.pE_KeepUpdateCheckBox.Enable=true;
                app.pE_KeepUpdateCheckBox.Value=false;
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Stop.'));
                app.HistoryTextArea.Value = flip(History,1);
                
                % turn off white light
                if strcmp(app.BFSwitch.Value,'On') && (app.BFConnectButton.Value==1)
                    app.BFSwitch.Value = 'Off';
                    app.BFLamp.Color = [0.9,0.9,0.9];
                    
                    % write command to daq
                    write(app.dq,0);
                    
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn on the BF.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
                
                % turn off pE4000
                if strcmp(app.pESwitch.Value,'On') && (app.pEConnectButton.Value==1)
                    app.mmc.setProperty('pE4000','Global State',0)
                    app.pESwitch.Value='Off';
                end
            end
        end

        % Value changed function: StageConnectButton
        function StageConnectButtonValueChanged(app, event)
            app.StageConnectButton.Enable=false;
            pause(0.1)
            value = app.StageConnectButton.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if (value==1)
                app.StageConnectButton.Text = 'Disconnect';
                app.StageConnectButton.BackgroundColor = [1,0,0];
                app.PFSSwitch.Enable = true;
                app.AddButton.Enable = true;
                app.FLC_Button_1.Enable=true;
                app.FLC_Button_2.Enable=true;
                app.FLC_Button_3.Enable=true;
                app.FLC_Button_4.Enable=true;
                app.FLC_Button_5.Enable=true;
                app.FLC_Button_6.Enable=true;
                app.EyeButton_6.Enable=true;
                app.R100Button3.Enable=true;
                app.L100Button2.Enable=true;
                app.XYZUpdateCheckBox.Enable = true;
                app.XYZUpdateCheckBox.Value = true;
                
                % create java library
                if app.ismmc==false
                    import mmcorej.*
                    app.mmc = CMMCore;
                    app.mmc.loadSystemConfiguration('MMConfig_TiE_XYZ.cfg');
                    app.ismmc=true;
                end
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Connect Nikon motorized stage.'));
                app.HistoryTextArea.Value = flip(History,1);
                
                % check PFS state
                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                    app.PFSSwitch.Value = 'On';
                    app.PFSLamp.Color = [0,1,0];
                    
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Keep PFS on.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
                
                % check fluorescence cube
                FLC_idx=str2double(app.mmc.getProperty('TIFilterBlock1','State'));
                switch FLC_idx
                    case 1
                        app.FLC_Button_1.Value=1;
                    case 2
                        app.FLC_Button_2.Value=1;
                    case 3
                        app.FLC_Button_3.Value=1;
                    case 4
                        app.FLC_Button_4.Value=1;
                    case 5
                        app.FLC_Button_5.Value=1;
                    case 0
                        app.FLC_Button_6.Value=1;
                end
                
                % check view port
                view_port=str2double(app.mmc.getProperty('TILightPath','State'));
                switch view_port
                    case 0
                        app.EyeButton_6.Value=1;
                    case 1
                        app.L100Button2.Value=1;
                    case 2
                        app.R100Button3.Value=1;
                end
            else
                app.StageConnectButton.Text = 'Connect';
                app.StageConnectButton.BackgroundColor = [0,1,0];
                app.PFSSwitch.Enable = false;
                app.AddButton.Enable = false;
                app.FLC_Button_1.Enable=false;
                app.FLC_Button_2.Enable=false;
                app.FLC_Button_3.Enable=false;
                app.FLC_Button_4.Enable=false;
                app.FLC_Button_5.Enable=false;
                app.FLC_Button_6.Enable=false;
                app.EyeButton_6.Enable=false;
                app.R100Button3.Enable=false;
                app.L100Button2.Enable=false;
                app.XYZUpdateCheckBox.Enable = false;
                app.XYZUpdateCheckBox.Value = false;
                
                if app.pEConnectButton.Value==0
                    app.mmc.reset()
                    app.ismmc=false;
                end
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Disonnect Nikon motorized stage.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
            app.StageConnectButton.Enable=true;
            
            %
            while (app.XYZUpdateCheckBox.Value==true) && (app.StartAcquiButton.Enable==false)
                %
                x = app.mmc.getXPosition();
                y = app.mmc.getYPosition();
                z = app.mmc.getPosition();
                pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                    app.PFSLamp.Color = [0,1,0];
                    app.PFSSwitch.Value = 'On';
                else
                    app.PFSLamp.Color = [0.9,0.9,0.9];
                    app.PFSSwitch.Value = 'Off';
                end
                app.XumEditField.Value = x;
                app.YumEditField.Value = y;
                app.ZumEditField.Value = z;
                app.PFSEditField.Value = pfs_z;
                
                %
                FLC_idx=str2double(app.mmc.getProperty('TIFilterBlock1','State'));
                switch FLC_idx
                    case 1
                        app.FLC_Button_1.Value=1;
                    case 2
                        app.FLC_Button_2.Value=1;
                    case 3
                        app.FLC_Button_3.Value=1;
                    case 4
                        app.FLC_Button_4.Value=1;
                    case 5
                        app.FLC_Button_5.Value=1;
                    case 0
                        app.FLC_Button_6.Value=1;
                end
                
                %
                view_port=str2double(app.mmc.getProperty('TILightPath','State'));
                switch view_port
                    case 0
                        app.EyeButton_6.Value=1;
                    case 1
                        app.L100Button2.Value=1;
                    case 2
                        app.R100Button3.Value=1;
                end
                
                % simultaneously update pE4000
                if (app.pE_KeepUpdateCheckBox.Value==1)
                    pE_state=str2double(app.mmc.getProperty('pE4000','Global State'));
                    if pE_state==1
                        app.pESwitch.Value='On';
                    else
                        app.pESwitch.Value='Off';
                    end
                    
                    
                    pE_A_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionA'));
                    if pE_A_selectTF==1
                        app.pE_ACheckBox.Value=1;
                        app.pE_ALamp.Color=[0.72,0.27,1.00];
                    else
                        app.pE_ACheckBox.Value=0;
                        app.pE_ALamp.Color=[0.90,0.90,0.90];
                    end
                    pE_A_wavelength=app.mmc.getProperty('pE4000','ChannelA');
                    if (sum(str2double(app.pE_ADropDown.Items)==str2double(pE_A_wavelength))==1)
                        app.pE_ADropDown.Value=pE_A_wavelength;
                        pE_A_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityA'));
                        app.pE_ASpinner.Value=pE_A_Intensity;
                    end
                    
                    pE_B_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionB'));
                    if pE_B_selectTF==1
                        app.pE_BCheckBox.Value=1;
                        app.pE_BLamp.Color=[0.07,0.62,1.00];
                    else
                        app.pE_BCheckBox.Value=0;
                        app.pE_BLamp.Color=[0.90,0.90,0.90];
                    end
                    pE_B_wavelength=app.mmc.getProperty('pE4000','ChannelB');
                    if (sum(str2double(app.pE_BDropDown.Items)==str2double(pE_B_wavelength))==1)
                        app.pE_BDropDown.Value=pE_B_wavelength;
                        pE_B_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityB'));
                        app.pE_BSpinner.Value=pE_B_Intensity;
                    end
                    
                    pE_C_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionC'));
                    if pE_C_selectTF==1
                        app.pE_CCheckBox.Value=1;
                        app.pE_CLamp.Color=[0.00,1.00,0.00];
                    else
                        app.pE_CCheckBox.Value=0;
                        app.pE_CLamp.Color=[0.90,0.90,0.90];
                    end
                    pE_C_wavelength=app.mmc.getProperty('pE4000','ChannelC');
                    if (sum(str2double(app.pE_CDropDown.Items)==str2double(pE_C_wavelength))==1)
                        app.pE_CDropDown.Value=pE_C_wavelength;
                        pE_C_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityC'));
                        app.pE_CSpinner.Value=pE_C_Intensity;
                    end
                    
                    pE_D_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionD'));
                    if pE_D_selectTF==1
                        app.pE_DCheckBox.Value=1;
                        app.pE_DLamp.Color=[1.00,0.00,0.00];
                    else
                        app.pE_DCheckBox.Value=0;
                        app.pE_DLamp.Color=[0.90,0.90,0.90];
                    end
                    pE_D_wavelength=app.mmc.getProperty('pE4000','ChannelD');
                    if (sum(str2double(app.pE_DDropDown.Items)==str2double(pE_D_wavelength))==1)
                        app.pE_DDropDown.Value=pE_D_wavelength;
                        pE_D_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityD'));
                        app.pE_DSpinner.Value=pE_D_Intensity;
                    end
                    
                    
                    % update nFluos
                    nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
                    app.nFluosEditField.Value=num2str(nFluos);
                    app.nFluoDropDown.Items = {};
                    app.fluo_schedule = [];
                    if (nFluos>0)
                        app.nFluoDropDown.Visible = true;
                        Items{1} = 'Choose...';
                        for i = 2:nFluos+1
                            Items{i} = strcat('Fluo-',num2str(i-1));
                            app.fluo_schedule(i-1,:) = [0,0,1];
                        end
                        app.nFluoDropDown.Items = Items;
                    else
                        app.nFluoDropDown.Visible = false;
                    end
                end
                
                pause(0.05)
            end
            
        end

        % Value changed function: PFSSwitch
        function PFSSwitchValueChanged(app, event)
            value = app.PFSSwitch.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if strcmp(value,'On')
                app.PFSLamp.Color = [0,1,0];
                app.mmc.setProperty('TIPFSStatus','State','On');
                pause(0.5)
                
                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'Off')
                    app.PFSLamp.Color = [0.9,.9,0.9];
                    app.PFSSwitch.Value = 'Off';
                    
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Out of PFS.'));
                    app.HistoryTextArea.Value = flip(History,1);
                else
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn on PFS.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
                
                
            else
                app.PFSLamp.Color = [0.9,0.9,0.9];
                app.mmc.setProperty('TIPFSStatus','State','Off');
                pause(0.5)
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn off PFS.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: SpuriousNoiseFilterCheckBox
        function SpuriousNoiseFilterCheckBoxValueChanged(app, event)
            %             value = app.SpuriousNoiseFilterCheckBox.Value
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Use spurious noise filter.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: CenterCheckBox
        function CenterCheckBoxValueChanged(app, event)
            %             value = app.CenterCheckBox.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Center ROI.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: OverlapCheckBox
        function OverlapCheckBoxValueChanged(app, event)
            %             value = app.OverlapCheckBox.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Use overlap.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % reset preview axes
            delete(app.hFig);
            app.hFig = figure('CloseRequestFcn','');
            width = app.WidthEditField.Value;
            height = app.HeightEditField.Value;
            app.hImage = imshow(zeros(height,width),'Border','Tight');
            set(gca,'DataAspectRatio',[1,1,1],'PlotBoxAspectRatio',[1,1,1])
            
            % CCD temperature
            app.UpdateButton.Enable = false;
            app.PreviewSwitch.Enable = true;
            [~,SensorTemp] = AT_GetFloat(app.hndl,'SensorTemperature');                     % get sensor temperaure
            app.TemperatureCEditField.Value = SensorTemp;
            
            % Center ROI
            if app.CenterCheckBox.Value==true
                AT_SetBool(app.hndl,'VerticallyCentreAOI',1);
            else
                AT_SetBool(app.hndl,'VerticallyCentreAOI',0);
            end
            [~,bool] = AT_GetBool(app.hndl,'VerticallyCentreAOI');
            if bool==1
                app.CenterCheckBox.Value = true;
            else
                app.CenterCheckBox.Value = false;
            end
            
            % Overlap
            if app.OverlapCheckBox.Value==true
                AT_SetBool(app.hndl,'Overlap',1);
            else
                AT_SetBool(app.hndl,'Overlap',0);
            end
            [~,bool] = AT_GetBool(app.hndl,'Overlap');
            if bool==1
                app.OverlapCheckBox.Value = true;
            else
                app.OverlapCheckBox.Value = false;
            end
            
            % SpuriousNoiseFilter
            if app.SpuriousNoiseFilterCheckBox.Value==true
                AT_SetBool(app.hndl,'SpuriousNoiseFilter',1);
            else
                AT_SetBool(app.hndl,'SpuriousNoiseFilter',0);
            end
            [~,bool] = AT_GetBool(app.hndl,'SpuriousNoiseFilter');
            if bool==1
                app.SpuriousNoiseFilterCheckBox.Value = true;
            else
                app.SpuriousNoiseFilterCheckBox.Value = false;
            end
            
            % ROI
            width = app.WidthEditField.Value;
            AT_SetInt(app.hndl,'AOIWidth',width);
            height = app.HeightEditField.Value;
            height=2*round(height/2);                                       % enforce height to an even number
            AT_SetInt(app.hndl,'AOIHeight',height);
            pause(0.1)
            [~,height] = AT_GetInt(app.hndl,'AOIHeight');
            app.HeightEditField.Value = height;
            [~,width] = AT_GetInt(app.hndl,'AOIWidth');
            app.WidthEditField.Value = width;
            %             [~,left] = AT_GetInt(app.hndl,'AOILeft');
            %             [~,stride] = AT_GetInt(app.hndl,'AOIStride');
            
            % gain mode
            value = app.GainmodeDropDown.Value;
            AT_SetEnumString(app.hndl,'SimplePreAmpGainControl',value);
            pause(0.1)
            [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');          % 0-12-bit (high well capacity), 1-12-bit (low noise), 2-16-bit (low noise & high well capacity)
            switch currentIndex
                case 0
                    app.GainmodeDropDown.Value = '12-bit (high well capacity)';
                case 1
                    app.GainmodeDropDown.Value = '12-bit (low noise)';
                case 2
                    app.GainmodeDropDown.Value = '16-bit (low noise & high well capacity)';
            end
            
            % trigger mode
            value = app.TriggermodeDropDown.Value;
            AT_SetEnumString(app.hndl,'TriggerMode',value);
            pause(0.1)
            [~,currentIndex] = AT_GetEnumIndex(app.hndl,'TriggerMode');
            switch currentIndex
                case 0
                    app.TriggermodeDropDown.Value = 'Internal';
                case 1
                    app.TriggermodeDropDown.Value = 'External Level Transition';
                case 2
                    app.TriggermodeDropDown.Value = 'External Start';
                case 3
                    app.TriggermodeDropDown.Value = 'External Exposure';
                case 4
                    app.TriggermodeDropDown.Value = 'Software';
                case 5
                    app.TriggermodeDropDown.Value = 'Advanced';
                case 6
                    app.TriggermodeDropDown.Value = 'External';
            end
            AT_SetEnumString(app.hndl,'CycleMode','Fixed');
            
            % readout
            value = app.ReadoutmodeDropDown.Value;
            AT_SetEnumString(app.hndl,'PixelReadoutRate',value);
            pause(0.1)
            [~,currentIndex] = AT_GetEnumIndex(app.hndl,'PixelReadoutRate');                 % 0-10 MHz, 1-100 MHz, 2-200 MHz, 3-270 MHz
            switch currentIndex
                case 0
                    app.ReadoutmodeDropDown.Value = '10 MHz';
                case 1
                    app.ReadoutmodeDropDown.Value = '100 MHz';
                case 2
                    app.ReadoutmodeDropDown.Value = '200 MHz';
                case 3
                    app.ReadoutmodeDropDown.Value = '270 MHz';
            end
            
            % exposure time
            ExposureTimeInSecond = app.ExposuretimemsecEditField.Value/1000;
            AT_SetFloat(app.hndl,'ExposureTime', ExposureTimeInSecond);
            pause(0.1)
            [~,ExposureTimeInSecond] = AT_GetFloat(app.hndl,'ExposureTime');
            app.ExposuretimemsecEditField.Value = ExposureTimeInSecond*1000;
            
            % fps
            [~,frameRate_Max] = AT_GetFloatMax(app.hndl,'FrameRate');
            AT_SetFloat(app.hndl,'FrameRate',floor(frameRate_Max));
            pause(0.1)
            [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
            app.FramepersecondHzEditField.Value=frameRate;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Update camera setting.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: ExposuretimemsecEditField
        function ExposuretimemsecEditFieldValueChanged(app, event)
            value = app.ExposuretimemsecEditField.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',num2str(value),'" msec as exposure time.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',num2str(value),'" pixels as width.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',num2str(value),'" pixels as height.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: GainmodeDropDown
        function GainmodeDropDownValueChanged(app, event)
            value = app.GainmodeDropDown.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',value,'" as gain mode.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: TriggermodeDropDown
        function TriggermodeDropDownValueChanged(app, event)
            value = app.TriggermodeDropDown.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',value,'" as trigger mode.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: ReadoutmodeDropDown
        function ReadoutmodeDropDownValueChanged(app, event)
            value = app.ReadoutmodeDropDown.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',value,'" as readout rate.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Button pushed function: ListButton
        function ListButtonPushed(app, event)
            dataselection(app);
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Open the recorded position list.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            x = app.mmc.getXPosition();
            y = app.mmc.getYPosition();
            z = app.mmc.getPosition();
            pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
            app.listing_pos = [app.listing_pos;x,y,z,pfs_z];
            app.roiCountEditField.Value = size(app.listing_pos,1);
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Add ROI ', num2str(size(app.listing_pos,1)),'.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: nTimersEditField
        function nTimersEditFieldValueChanged(app, event)
            value = app.nTimersEditField.Value;
            app.nTimerDropDown.Items = {};
            app.schedule = [];
            if (value>0)
                app.nTimerDropDown.Visible = true;
                Items{1} = 'Choose...';
                for i = 2:value+1
                    Items{i} = strcat('Timer-',num2str(i-1));
                    app.schedule(i-1,:) = [0,0];
                end
                app.nTimerDropDown.Items = Items;
            else
                app.nTimerDropDown.Visible = false;
            end
        end

        % Value changed function: nTimerDropDown
        function nTimerDropDownValueChanged(app, event)
            value = app.nTimerDropDown.Value;
            for i = 1:app.nTimersEditField.Value
                if strcmp(strcat('Timer-',num2str(i)),value)
                    nTimer = i;
                end
            end
            
            prompt = {'\fontsize{16} Enter interval time [sec]:','\fontsize{16} Enter the number of loop:'};
            dlgtitle = 'Input';
            dims = [1 66];
            opts.Interpreter = 'tex';
            definput = {num2str(app.schedule(nTimer,1)),num2str(app.schedule(nTimer,2))};
            answer = inputdlg(prompt,dlgtitle,dims,definput,opts);
            if ~isempty(answer)
                app.schedule(nTimer,:) = [str2double(answer{1}),str2double(answer{2})];
            end
            app.nLoopsEditField.Value = sum(app.schedule(:,2));
            app.schedule
            app.nTimerDropDown.Value = 'Choose...';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            checksum = sum(app.schedule(:,1).*app.schedule(:,2)==0);
            checkupdate = app.UpdateButton.Enable;
            
            % build
            fluo_ex_list_ = [0,0,0,0];
            if app.pE_ACheckBox.Value==1
                fluo_ex_list_(1)=1;
            end
            if app.pE_BCheckBox.Value==1
                fluo_ex_list_(2)=1;
            end
            if app.pE_CCheckBox.Value==1
                fluo_ex_list_(3)=1;
            end
            if app.pE_DCheckBox.Value==1
                fluo_ex_list_(4)=1;
            end
            app.fluo_ex_idx=find(fluo_ex_list_==1);
            
            
            if checkupdate==1
                app.StartAcquiButton.Enable = 0;
                errordlg('Please update camera setting.','Reminder.')
            else
                if checksum>0
                    History = flip(app.HistoryTextArea.Value,1);
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Timer setting occured an error.'));
                    app.HistoryTextArea.Value = flip(History,1);
                else
                    
                    app.StartAcquiButton.Enable = true;
                    for i = 1:size(app.schedule,1)
                        IntervalTimeInSecond = app.schedule(i,1);
                        app.schedule_timer.timers(i) = timer('TimerFcn',@(~,~) disp(datetime(now,'ConvertFrom','datenum')),...
                            'StartDelay',IntervalTimeInSecond);
                    end
                    app.schedule_timer.timers
                    
                    % allocate buffer
                    [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
                    nFrames = app.nFramesEditField.Value;
                    AT_SetInt(app.hndl,'FrameCount',nFrames);
                    for X = 1:nFrames
                        AT_QueueBuffer(app.hndl,imagesize);
                    end
                    
                    History = flip(app.HistoryTextArea.Value,1);
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Ready to start.'));
                    app.HistoryTextArea.Value = flip(History,1);
                    
                end
            end
        end

        % Button pushed function: StartAcquiButton
        function StartAcquiButtonPushed(app, event)

            checksum = 0;
            
            if app.HeightEditField.Value<=128 && app.CorrectXYCheckBox.Value==1
                errordlg('Please disable "XY correction", because the height must be greater than 128.','Reminder.')
            else
                checksum = checksum +1;
            end
            
            
            value = app.BFSwitch.Value;
            if strcmp(value,'On')
                errordlg('Please turn off the white light.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            value = app.PreviewSwitch.Value;
            if strcmp(value,'On')
                errordlg('Please stop previewing.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            outputdir = app.FolderpathEditField.Value;
            if isempty(outputdir)
                errordlg('Please choose the working folder.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            value = app.TriggermodeDropDown.Value;
            if ~strcmp(value,'Internal')
                errordlg('Please use "Internal" mode as trigger mode.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            value = app.OverlapCheckBox.Value;
            if value==0
                errordlg('Please use "Overlap" mode.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            value = app.XYZUpdateCheckBox.Value;
            if value==1
                errordlg('Please disable "Keep updating motorized stage".','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            value=app.pE_KeepUpdateCheckBox.Value;
            if value==1
                errordlg('Please disable "Keep updating pE4000".','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            
            if (checksum==8)
                app.StartAcquiButton.Enable = false;
                app.nFramesEditField.Enable = false;
                app.nLoopsEditField.Enable = false;
                app.nTimersEditField.Enable = false;
                app.nTimerDropDown.Enable = false;
                app.XYZUpdateCheckBox.Enable = false;
                app.XYZUpdateCheckBox.Value = false;
                app.ChooseButton.Enable = false;
                app.BFConnectButton.Enable = false;
                app.BFSwitch.Enable = false;
                app.StageConnectButton.Enable = false;
                app.ListButton.Enable = false;
                app.AddButton.Enable = false;
                app.PFSSwitch.Enable = false;
                app.CamConnectButton.Enable = false;
                app.PreviewSwitch.Enable = false;
                app.ExposuretimemsecEditField.Enable = false;
                app.GainmodeDropDown.Enable = false;
                app.ReadoutmodeDropDown.Enable = false;
                app.TriggermodeDropDown.Enable = false;
                app.SpuriousNoiseFilterCheckBox.Enable = false;
                app.OverlapCheckBox.Enable = false;
                app.WidthEditField.Enable = false;
                app.HeightEditField.Enable = false;
                app.CenterCheckBox.Enable = false;
                app.ApplyButton.Enable = false;
                app.CorrectXYCheckBox.Enable = false;
                app.CorrectZCheckBox.Enable = false;
                app.FLC_Button_1.Enable=false;
                app.FLC_Button_2.Enable=false;
                app.FLC_Button_3.Enable=false;
                app.FLC_Button_4.Enable=false;
                app.FLC_Button_5.Enable=false;
                app.FLC_Button_6.Enable=false;
                app.EyeButton_6.Enable=false;
                app.R100Button3.Enable=false;
                app.L100Button2.Enable=false;
                app.nFluoDropDown.Enable=false;
                app.pEConnectButton.Enable=false;
                app.pESwitch.Enable=false;
                app.pE_ACheckBox.Enable=false;
                app.pE_ADropDown.Enable=false;
                app.pE_ASpinner.Enable=false;
                app.pE_BCheckBox.Enable=false;
                app.pE_BDropDown.Enable=false;
                app.pE_BSpinner.Enable=false;
                app.pE_CCheckBox.Enable=false;
                app.pE_CDropDown.Enable=false;
                app.pE_CSpinner.Enable=false;
                app.pE_DCheckBox.Enable=false;
                app.pE_DDropDown.Enable=false;
                app.pE_DSpinner.Enable=false;
                app.pE_KeepUpdateCheckBox.Enable=false;
                app.pE_ALamp.Color=[0.90,0.90,0.90];
                app.pE_BLamp.Color=[0.90,0.90,0.90];
                app.pE_CLamp.Color=[0.90,0.90,0.90];
                app.pE_DLamp.Color=[0.90,0.90,0.90];
                
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Start...'));
                app.HistoryTextArea.Value = flip(History,1);
                
                nLoops = sum(app.schedule(:,2));
                ntimer = 1;
                nLoops_limit = app.schedule(ntimer,2);
                nPoss = size(app.listing_pos,1);
                
                for nPos = 1:nPoss
                    outputsubdir = [outputdir,'\Pos',num2str(nPos)];
                    mkdir(outputsubdir);
                end
                
                % save original position
                outputfile = strcat(outputdir,'\',string(datetime(floor(now),'ConvertFrom','datenum')),'_listing_pos_T_0.mat');
                Pos0 = app.listing_pos;
                save(outputfile,'Pos0');
                
                % initialization
                mag=app.MagEditField.Value;
                pixelsize=6.5;                                              % microns
                tol_xy=7;                                                   % microns
                TF_CorrectXY = app.CorrectXYCheckBox.Value;
                TF_CorrectZ = app.CorrectZCheckBox.Value;
                CorrectXY_map = zeros(nPoss,nLoops,3);
                CorrectZ_map = zeros(nPoss,nLoops,2);
                nFrames = app.nFramesEditField.Value;
                height = app.HeightEditField.Value;
                width = app.WidthEditField.Value;
                [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
                [~,stride] = AT_GetInt(app.hndl,'AOIStride');
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');
                database = uint16(zeros(height,width,nFrames));
                [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
                disp(['The acquisition rate is ',num2str(frameRate),' Hz.'])
                disp(strcat('Running...',string(datetime(now,'ConvertFrom','datenum'))));
                for nLoop = 1:nLoops
                    if nLoop<nLoops_limit
                        T = app.schedule_timer.timers(ntimer);
                    elseif nLoop==nLoops
                        T = app.schedule_timer.timers(ntimer);
                    else
                        ntimer=ntimer+1;
                        nLoops_limit = nLoops_limit+app.schedule(ntimer,2);
                        T = app.schedule_timer.timers(ntimer);
                    end
                    start(T)
                    
                    disp(nLoop)
                    for nPos = 1:nPoss
                        
                        app.StreamLamp.Color = [0,1,0];
                        
                        % set fast-timelapse fps
                        ExposureTimeInSecond = app.ExposuretimemsecEditField.Value/1000;
                        AT_SetFloat(app.hndl,'ExposureTime', ExposureTimeInSecond);
                        pause(0.1)
                        [~,frameRate_Max] = AT_GetFloatMax(app.hndl,'FrameRate');
                        AT_SetFloat(app.hndl,'FrameRate',floor(frameRate_Max));
                        pause(0.1)
                        [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
                        disp(['The acquisition rate is ',num2str(frameRate),' Hz.'])


                        % move stage
                        app.mmc.setXYPosition(app.listing_pos(nPos,1),app.listing_pos(nPos,2));
                        if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'Off')
                            app.mmc.setPosition(app.listing_pos(nPos,3));
                        else
                            app.mmc.setProperty('TIPFSOffset','Position',num2str(app.listing_pos(nPos,4)));
                        end
                        pause(2) % wating for stable
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % turn on the white light
                        write(app.dq,5);
                        app.BFLamp.Color = [1.00,1.00,0.00];
                        pause(0.1)
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                        % XY correction %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if (TF_CorrectXY==1)
                            if (nLoop>1)
                                if (currentIndex<2)
                                    AT_Command(app.hndl,'AcquisitionStart');
                                    for i = 0:nFrames-1
                                        [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                        AT_QueueBuffer(app.hndl,imagesize);
                                        [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                                        database(:,:,i+1) = flip(transpose(buf2),2);
                                    end
                                    AT_Command(app.hndl,'AcquisitionStop');
                                else
                                    AT_Command(app.hndl,'AcquisitionStart');
                                    for i = 0:nFrames-1
                                        [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                        AT_QueueBuffer(app.hndl,imagesize);
                                        [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                                        database(:,:,i+1) = flip(transpose(buf2),2);
                                    end
                                    AT_Command(app.hndl,'AcquisitionStop');
                                end
                                
                                if height>256
                                    sx=width/2-128;
                                    sy=height/2-128;
                                    ex=width/2+127;
                                    ey=height/2+127;
                                    x0=65;
                                    y0=65;
                                end
                                
                                if (height<=256) && (height>128)
                                    sx=width/2-64;
                                    sy=height/2-64;
                                    ex=width/2+63;
                                    ey=height/2+63;
                                    x0=33;
                                    y0=33;
                                end
                                
                                database1 = mean(database(sy:ey,sx:ex,:),3);
                                [shiftY,shiftX] = driftXY_FFTCorr(app.database0(:,:,nPos),database1);
                                if (abs((shiftX-x0)*pixelsize/mag) <=tol_xy) && (abs((shiftY-y0)*pixelsize/mag) <=tol_xy)
                                    correct_pos_x = app.mmc.getXPosition()+(shiftX-x0)*pixelsize/mag;
                                    correct_pos_y = app.mmc.getYPosition()-(shiftY-y0)*pixelsize/mag;
                                    app.mmc.setXYPosition(correct_pos_x,correct_pos_y);
                                    pause(0.5)                              % wait for stable
                                    app.listing_pos(nPos,1) = correct_pos_x;
                                    app.listing_pos(nPos,2) = correct_pos_y;
                                    CorrectXY_map(nPos,nLoop,1) = 1;
                                    CorrectXY_map(nPos,nLoop,2) = (shiftX-x0)*pixelsize/mag;
                                    CorrectXY_map(nPos,nLoop,3) = -(shiftY-y0)*pixelsize/mag;
                                else
                                    CorrectXY_map(nPos,nLoop,1) = nan;
                                end
                            end
                        end
                        
                        % z correction %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if (TF_CorrectZ==1)
                            if (nLoop>1)
                                target = Z_targets(:,:,nPos);
                                target = (target-mean2(target))/std2(target);
                                C = [];
                                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                                    for zstep = -8:8
                                        z = app.listing_pos(nPos,4) + zstep*0.5;
                                        app.mmc.setProperty('TIPFSOffset','Position',num2str(z));
                                        if (zstep==-8)
                                            pause(3)
                                        else
                                            pause(1)
                                        end
                                        
                                        if (currentIndex<2)
                                            AT_Command(app.hndl,'AcquisitionStart');
                                            for i = 0:nFrames-1
                                                [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                                AT_QueueBuffer(app.hndl,imagesize);
                                                [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                                                database(:,:,i+1) = flip(transpose(buf2),2);
                                            end
                                            AT_Command(app.hndl,'AcquisitionStop');
                                        else
                                            AT_Command(app.hndl,'AcquisitionStart');
                                            for i = 0:nFrames-1
                                                [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                                AT_QueueBuffer(app.hndl,imagesize);
                                                [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                                                database(:,:,i+1) = flip(transpose(buf2),2);
                                            end
                                            AT_Command(app.hndl,'AcquisitionStop');
                                        end
                                        
                                        suspect = double(database(:,:,end));
                                        suspect = (suspect-mean2(suspect))/std2(suspect);
                                        C = [C,mean2(target.*suspect)];
                                    end
                                end
                                
                                dz = (-8:8)*0.5;
                                z = app.listing_pos(nPos,4) + dz(C==max(C));
                                app.mmc.setProperty('TIPFSOffset','Position',num2str(z));
                                app.listing_pos(nPos,4) = z;
                                CorrectZ_map(nPos,nLoop,1) = 1;
                                CorrectZ_map(nPos,nLoop,2) = dz(C==max(C));
                                pause(2)
                            end
                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        % kinectic series
                        if (currentIndex<2)
                            AT_Command(app.hndl,'AcquisitionStart');
                            for i = 0:nFrames-1
                                [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                AT_QueueBuffer(app.hndl,imagesize);
                                [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                                database(:,:,i+1) = flip(transpose(buf2),2);
                            end
                            AT_Command(app.hndl,'AcquisitionStop');
                        else
                            AT_Command(app.hndl,'AcquisitionStart');
                            for i = 0:nFrames-1
                                [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                AT_QueueBuffer(app.hndl,imagesize);
                                [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                                database(:,:,i+1) = flip(transpose(buf2),2);
                            end
                            AT_Command(app.hndl,'AcquisitionStop');
                        end
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % turn off the white light
                        write(app.dq,0);
                        app.BFLamp.Color = [0.9,0.9,0.9];
                        pause(0.1)
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        
                        % write database into disk
                        outputfile = [outputdir,'\Pos',num2str(nPos),'\T_',num2str(nLoop-1),'.tif'];
                        for nFrame = 1:nFrames
                            imwrite(database(:,:,nFrame),outputfile,'WriteMode','append','Compression','None');
                        end
                        
                        % do target pattern
                        if (TF_CorrectXY==1)
                            if (nLoop==1)
                                if height>256
                                    app.database0(:,:,nPos) = zeros(256,256);
                                    database00 = mean(database(height/2-64:height/2+63,width/2-64:width/2+63,:),3);
                                    database00 = database00-mean2(database00);
                                    database00 = database00/std2(database00);
                                    app.database0(1:128,1:128,nPos) = database00;
                                end
                                
                                if (height<=256) && (height>128)
                                    app.database0(:,:,nPos) = zeros(128,128);
                                    database00 = mean(database(height/2-32:height/2+31,width/2-32:width/2+31,:),3);
                                    database00 = database00-mean2(database00);
                                    database00 = database00/std2(database00);
                                    app.database0(1:64,1:64,nPos) = database00;
                                end
                            end
                        end
                        
                        if (TF_CorrectZ==1)
                            if (nLoop==1)
                                Z_targets(:,:,nPos) = double(database(:,:,end));
                            end
                        end
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % add "capture fluo"
                        if ~isempty(app.fluo_schedule)
                            nFluos=size(app.fluo_schedule,1);
                            for nFluo = 1:nFluos
                                % set fluorescence fps
                                ExposureTimeInSecond = app.fluo_schedule(nFluo,2)/1000;
                                AT_SetFloat(app.hndl,'ExposureTime', ExposureTimeInSecond);
                                pause(0.1)
                                [~,frameRate_Max] = AT_GetFloatMax(app.hndl,'FrameRate');
                                AT_SetFloat(app.hndl,'FrameRate',floor(frameRate_Max));
                                pause(0.1)
                                [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
                                disp(['The acquisition rate is ',num2str(frameRate),' Hz.'])
                                
                                % move stage
                                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'Off')
                                    app.mmc.setPosition(app.listing_pos(nPos,3)-23.5);
                                else
                                    app.mmc.setProperty('TIPFSOffset','Position',num2str(app.listing_pos(nPos,4)));
                                end
                                pause(2) % wating for stable

                                % change fluo cube
                                FLC_idx=app.fluo_schedule(nFluo,1);
                                if FLC_idx==6
                                    FLC_idx=0;
                                end
                                app.mmc.setProperty('TIFilterBlock1','State',FLC_idx);
                                pause(3)
                                
                                switch FLC_idx
                                    case 1
                                        app.FLC_Button_1.Value=1;
                                    case 2
                                        app.FLC_Button_2.Value=1;
                                    case 3
                                        app.FLC_Button_3.Value=1;
                                    case 4
                                        app.FLC_Button_4.Value=1;
                                    case 5
                                        app.FLC_Button_5.Value=1;
                                    case 0
                                        app.FLC_Button_6.Value=1;
                                end
                                
                                % turn on pE4000
                                fluo_ex_idx_=app.fluo_ex_idx(nFluo);
                                switch fluo_ex_idx_
                                    case 1
                                        app.mmc.setProperty('pE4000','SelectionA',1)
                                        pause(0.1)
                                        app.pE_ALamp.Color=[0.72,0.27,1.00];
%                                         pE_A_wavelength=str2double(app.pE_ADropDown.Value);
%                                         app.mmc.setProperty('pE4000','ChannelA',pE_A_wavelength)
%                                         pause(0.5)
                                        app.mmc.setProperty('pE4000','SelectionB',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionC',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionD',0)
                                        pause(0.1)
                                    case 2
                                        app.mmc.setProperty('pE4000','SelectionA',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionB',1)
                                        pause(0.1)
                                        app.pE_BLamp.Color=[0.07,0.62,1.00];
%                                         pE_B_wavelength=str2double(app.pE_BDropDown.Value);
%                                         app.mmc.setProperty('pE4000','ChannelB',pE_B_wavelength)
%                                         pause(0.5)
                                        app.mmc.setProperty('pE4000','SelectionC',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionD',0)
                                        pause(0.1)
                                    case 3
                                        app.mmc.setProperty('pE4000','SelectionA',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionB',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionC',1)
                                        pause(0.1)
                                        app.pE_CLamp.Color=[0.00,1.00,0.00];
%                                         pE_C_wavelength=str2double(app.pE_CDropDown.Value);
%                                         app.mmc.setProperty('pE4000','ChannelC',pE_C_wavelength)
%                                         pause(0.5)
                                        app.mmc.setProperty('pE4000','SelectionD',0)
                                        pause(0.1)
                                    case 4
                                        app.mmc.setProperty('pE4000','SelectionA',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionB',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionC',0)
                                        pause(0.1)
                                        app.mmc.setProperty('pE4000','SelectionD',1)
                                        pause(0.1)
                                        app.pE_DLamp.Color=[1.00,0.00,0.00];
%                                         pE_D_wavelength=str2double(app.pE_DDropDown.Value);
%                                         app.mmc.setProperty('pE4000','ChannelD',pE_D_wavelength)
%                                         pause(0.5)
                                end
                                app.mmc.setProperty('pE4000','Global State',1)
                                pause(0.1)
                                
                                % kinectic series
                                if (currentIndex<2)
                                    AT_Command(app.hndl,'AcquisitionStart');
                                    for i = 0:app.fluo_schedule(nFluo,3)-1
                                        [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                        AT_QueueBuffer(app.hndl,imagesize);
                                        [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                                        database(:,:,i+1) = flip(transpose(buf2),2);
                                    end
                                    AT_Command(app.hndl,'AcquisitionStop');
                                else
                                    AT_Command(app.hndl,'AcquisitionStart');
                                    for i = 0:app.fluo_schedule(nFluo,3)-1
                                        [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                        AT_QueueBuffer(app.hndl,imagesize);
                                        [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                                        database(:,:,i+1) = flip(transpose(buf2),2);
                                    end
                                    AT_Command(app.hndl,'AcquisitionStop');
                                end
                                
                                
                                % turn off pE4000
                                app.mmc.setProperty('pE4000','Global State',0)
                                pause(1)
                                app.pE_ALamp.Color=[0.90,0.90,0.90];
                                app.pE_BLamp.Color=[0.90,0.90,0.90];
                                app.pE_CLamp.Color=[0.90,0.90,0.90];
                                app.pE_DLamp.Color=[0.90,0.90,0.90];
                                
                                % save images
                                outputfile = [outputdir,'\Pos',num2str(nPos),'\T_',num2str(nLoop-1),'_Fluo',num2str(nFluo),'.tif'];
                                for nFrame = 1:app.fluo_schedule(nFluo,3)
                                    imwrite(database(:,:,nFrame),outputfile,'WriteMode','append','Compression','None');
                                end
                            end
                            
                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        app.StreamLamp.Color = [0.9,0.9,0.9];
                        
                    end


                    if nLoop<nLoops
                        wait(T)
                    else
                        stop(T)
                        delete(T)
                    end
                end
                AT_Flush(app.hndl);
                
                % save last position
                outputfile = strcat(outputdir,'\',string(datetime(floor(now),'ConvertFrom','datenum')),'_listing_pos_T_',num2str(nLoop-1),'.mat');
                Pos0 = app.listing_pos;
                save(outputfile,'Pos0');
                
                % save correction xy drift
                if (TF_CorrectXY==1)
                    outputfile = [outputdir,'\CorrectXY_map.mat'];
                    save(outputfile,'CorrectXY_map');
                end
                
                if (TF_CorrectZ==1)
                    outputfile = [outputdir,'\CorrectZ_map.mat'];
                    save(outputfile,'CorrectZ_map');
                end
                
                app.nFramesEditField.Enable = true;
                app.nLoopsEditField.Enable = true;
                app.nTimersEditField.Enable = true;
                app.nTimerDropDown.Enable = true;
                app.XYZUpdateCheckBox.Enable = true;
                app.ChooseButton.Enable = true;
                app.BFConnectButton.Enable = true;
                app.BFSwitch.Enable = true;
                app.StageConnectButton.Enable = true;
                app.ListButton.Enable = true;
                app.AddButton.Enable = true;
                app.PFSSwitch.Enable = true;
                app.CamConnectButton.Enable = true;
                app.PreviewSwitch.Enable = true;
                app.ExposuretimemsecEditField.Enable = true;
                app.GainmodeDropDown.Enable = true;
                app.ReadoutmodeDropDown.Enable = true;
                app.TriggermodeDropDown.Enable = true;
                app.SpuriousNoiseFilterCheckBox.Enable = true;
                app.OverlapCheckBox.Enable = true;
                app.WidthEditField.Enable = true;
                app.HeightEditField.Enable = true;
                app.CenterCheckBox.Enable = true;
                app.ApplyButton.Enable = true;
                app.CorrectXYCheckBox.Enable = true;
                app.CorrectZCheckBox.Enable = true;
                app.FLC_Button_1.Enable=true;
                app.FLC_Button_2.Enable=true;
                app.FLC_Button_3.Enable=true;
                app.FLC_Button_4.Enable=true;
                app.FLC_Button_5.Enable=true;
                app.FLC_Button_6.Enable=true;
                app.EyeButton_6.Enable=true;
                app.R100Button3.Enable=true;
                app.L100Button2.Enable=true;
                app.nFluoDropDown.Enable=true;
                app.pEConnectButton.Enable=true;
                app.pESwitch.Enable=true;
                app.pE_ACheckBox.Enable=true;
                app.pE_ADropDown.Enable=true;
                app.pE_ASpinner.Enable=true;
                app.pE_BCheckBox.Enable=true;
                app.pE_BDropDown.Enable=true;
                app.pE_BSpinner.Enable=true;
                app.pE_CCheckBox.Enable=true;
                app.pE_CDropDown.Enable=true;
                app.pE_CSpinner.Enable=true;
                app.pE_DCheckBox.Enable=true;
                app.pE_DDropDown.Enable=true;
                app.pE_DSpinner.Enable=true;
                app.pE_KeepUpdateCheckBox.Enable=true;
                
                % recovery pE4000 configuration
                if ~isempty(app.fluo_schedule)
                    for nFluo=1:nFluos
                        fluo_ex_idx_=app.fluo_ex_idx(nFluo);
                        switch fluo_ex_idx_
                            case 1
                                app.mmc.setProperty('pE4000','SelectionA',1)
                                pE_A_wavelength=str2double(app.pE_ADropDown.Value);
                                app.mmc.setProperty('pE4000','ChannelA',pE_A_wavelength)
                                app.pE_ALamp.Color=[0.72,0.27,1.00];
                            case 2
                                app.mmc.setProperty('pE4000','SelectionB',1)
                                pE_B_wavelength=str2double(app.pE_BDropDown.Value);
                                app.mmc.setProperty('pE4000','ChannelB',pE_B_wavelength)
                                app.pE_BLamp.Color=[0.07,0.62,1.00];
                            case 3
                                app.mmc.setProperty('pE4000','SelectionC',1)
                                pE_C_wavelength=str2double(app.pE_CDropDown.Value);
                                app.mmc.setProperty('pE4000','ChannelC',pE_C_wavelength)
                                app.pE_CLamp.Color=[0.00,1.00,0.00];
                            case 4
                                app.mmc.setProperty('pE4000','SelectionD',1)
                                pE_D_wavelength=str2double(app.pE_DDropDown.Value);
                                app.mmc.setProperty('pE4000','ChannelD',pE_D_wavelength)
                                app.pE_DLamp.Color=[1.00,0.00,0.00];
                        end
                    end
                end
                
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Done.'));
                app.HistoryTextArea.Value = flip(History,1);
                
                app.database0=[];
            end
        end

        % Value changed function: XYZUpdateCheckBox
        function XYZUpdateCheckBoxValueChanged(app, event)
            value = app.XYZUpdateCheckBox.Value;
            while (value==true)
                if (app.XYZUpdateCheckBox.Value==false)
                    break;
                else
                    x = app.mmc.getXPosition();
                    y = app.mmc.getYPosition();
                    z = app.mmc.getPosition();
                    pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
                    if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                        app.PFSLamp.Color = [0,1,0];
                        app.PFSSwitch.Value = 'On';
                    else
                        app.PFSLamp.Color = [0.9,0.9,0.9];
                        app.PFSSwitch.Value = 'Off';
                    end
                    app.XumEditField.Value = x;
                    app.YumEditField.Value = y;
                    app.ZumEditField.Value = z;
                    app.PFSEditField.Value = pfs_z;
                    
                    %
                    FLC_idx=str2double(app.mmc.getProperty('TIFilterBlock1','State'));
                    switch FLC_idx
                        case 1
                            app.FLC_Button_1.Value=1;
                        case 2
                            app.FLC_Button_2.Value=1;
                        case 3
                            app.FLC_Button_3.Value=1;
                        case 4
                            app.FLC_Button_4.Value=1;
                        case 5
                            app.FLC_Button_5.Value=1;
                        case 0
                            app.FLC_Button_6.Value=1;
                    end
                    
                    %
                    view_port=str2double(app.mmc.getProperty('TILightPath','State'));
                    switch view_port
                        case 0
                            app.EyeButton_6.Value=1;
                        case 1
                            app.L100Button2.Value=1;
                        case 2
                            app.R100Button3.Value=1;
                    end
                    
                    % check whether simultaneously update pE4000
                    if (app.pE_KeepUpdateCheckBox.Value==1)
                        % ask current pE4000 configuration
                        pE_state=str2double(app.mmc.getProperty('pE4000','Global State'));
                        if pE_state==1
                            app.pESwitch.Value='On';
                        else
                            app.pESwitch.Value='Off';
                        end
                        
                        pE_A_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionA'));
                        if pE_A_selectTF==1
                            app.pE_ACheckBox.Value=1;
                            app.pE_ALamp.Color=[0.72,0.27,1.00];
                        else
                            app.pE_ACheckBox.Value=0;
                            app.pE_ALamp.Color=[0.90,0.90,0.90];
                        end
                        pE_A_wavelength=app.mmc.getProperty('pE4000','ChannelA');
                        if (sum(str2double(app.pE_ADropDown.Items)==str2double(pE_A_wavelength))==1)
                            app.pE_ADropDown.Value=pE_A_wavelength;
                            pE_A_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityA'));
                            app.pE_ASpinner.Value=pE_A_Intensity;
                        end
                        
                        pE_B_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionB'));
                        if pE_B_selectTF==1
                            app.pE_BCheckBox.Value=1;
                            app.pE_BLamp.Color=[0.07,0.62,1.00];
                        else
                            app.pE_BCheckBox.Value=0;
                            app.pE_BLamp.Color=[0.90,0.90,0.90];
                        end
                        pE_B_wavelength=app.mmc.getProperty('pE4000','ChannelB');
                        if (sum(str2double(app.pE_BDropDown.Items)==str2double(pE_B_wavelength))==1)
                            app.pE_BDropDown.Value=pE_B_wavelength;
                            pE_B_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityB'));
                            app.pE_BSpinner.Value=pE_B_Intensity;
                        end
                        
                        pE_C_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionC'));
                        if pE_C_selectTF==1
                            app.pE_CCheckBox.Value=1;
                            app.pE_CLamp.Color=[0.00,1.00,0.00];
                        else
                            app.pE_CCheckBox.Value=0;
                            app.pE_CLamp.Color=[0.90,0.90,0.90];
                        end
                        pE_C_wavelength=app.mmc.getProperty('pE4000','ChannelC');
                        if (sum(str2double(app.pE_CDropDown.Items)==str2double(pE_C_wavelength))==1)
                            app.pE_CDropDown.Value=pE_C_wavelength;
                            pE_C_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityC'));
                            app.pE_CSpinner.Value=pE_C_Intensity;
                        end
                        
                        pE_D_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionD'));
                        if pE_D_selectTF==1
                            app.pE_DCheckBox.Value=1;
                            app.pE_DLamp.Color=[1.00,0.00,0.00];
                        else
                            app.pE_DCheckBox.Value=0;
                            app.pE_DLamp.Color=[0.90,0.90,0.90];
                        end
                        pE_D_wavelength=app.mmc.getProperty('pE4000','ChannelD');
                        if (sum(str2double(app.pE_DDropDown.Items)==str2double(pE_D_wavelength))==1)
                            app.pE_DDropDown.Value=pE_D_wavelength;
                            pE_D_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityD'));
                            app.pE_DSpinner.Value=pE_D_Intensity;
                        end
                        
                        
                        % update nFluos
                        nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
                        app.nFluosEditField.Value=num2str(nFluos);
                        app.nFluoDropDown.Items = {};
                        app.fluo_schedule = [];
                        if (nFluos>0)
                            app.nFluoDropDown.Visible = true;
                            Items{1} = 'Choose...';
                            for i = 2:nFluos+1
                                Items{i} = strcat('Fluo-',num2str(i-1));
                                app.fluo_schedule(i-1,:) = [0,0,1];
                            end
                            app.nFluoDropDown.Items = Items;
                        else
                            app.nFluoDropDown.Visible = false;
                        end
                    end
                    
                    pause(0.05)
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            checksum = 0;
            if app.BFConnectButton.Value==1
                errordlg('Please disconnet the white light.','Reminder.')
            else
                checksum = checksum+1;
            end
            
            if app.CamConnectButton.Value==1
                errordlg('Please disconnect camera.','Reminder.')
            else
                checksum = checksum+1;
            end
            
            if app.StageConnectButton.Value==1
                errordlg('Please disconnect stage.','Reminder.')
            else
                checksum = checksum+1;
            end
            
            if checksum==3
                delete(app)
            end
            
        end

        % Value changed function: CorrectXYCheckBox
        function CorrectXYCheckBoxValueChanged(app, event)
            value = app.CorrectXYCheckBox.Value;
            if value ==1
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Correct drifting XY.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Not to correct drifting XY.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
            
        end

        % Value changed function: CorrectZCheckBox
        function CorrectZCheckBoxValueChanged(app, event)
            value = app.CorrectZCheckBox.Value;
            if value ==1
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Lock z plane.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Unlock z plane.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Button pushed function: Start2Button
        function Start2ButtonPushed(app, event)
            % allocate buffer
            [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
            nFrames = 1;
            AT_SetInt(app.hndl,'FrameCount',nFrames);
            for X = 1:nFrames
                AT_QueueBuffer(app.hndl,imagesize);
            end
            
            %
            outputdir = app.FolderpathEditField.Value;
            height = app.HeightEditField.Value;
            width = app.WidthEditField.Value;
            x1 = app.listing_pos(1,1);
            y1 = app.listing_pos(1,2);
            x2 = app.listing_pos(2,1);
            y2 = app.listing_pos(2,2);
            nxs = round(abs((x2-x1)/(width*(6.5/100))))+1
            nys = round(abs((y2-y1)/(height*(6.5/100))))+1
            nPos = 0;
            if x2-x1>0
                x_direction = 1;
            else
                x_direction = -1;
            end
            
            if y2-y1>0
                y_direction = 1;
            else
                y_direction = -1;
            end
            
            %
            [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
            [~,stride] = AT_GetInt(app.hndl,'AOIStride');
            [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');
            database = uint16(zeros(height,width,nFrames));
            [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
            disp(['The acquisition rate is ',num2str(frameRate),' Hz.'])
            disp(strcat('Running...',string(datetime(now,'ConvertFrom','datenum'))));
            
            %
            % turn on the white light
            write(app.dq,5);
            app.BFLamp.Color = [1.00,1.00,0.00];
            pause(0.1)
            
            for nx = 1:nxs
                
                if nx>1
                    x = x + x_direction*width*(6.5/100);
                else
                    x = x1;
                    y = y1;
                end
                
                for ny = 1:nys
                    nPos = nPos+1;
                    if ny>1
                        y = y + y_direction*height*(6.5/100);
                    end
                    
                    % move stage
                    app.mmc.setXYPosition(x,y);
                    pause(0.5)
                    
                    % kinectic series
                    if (currentIndex<2)
                        AT_Command(app.hndl,'AcquisitionStart');
                        for i = 0:nFrames-1
                            [~,buf] = AT_WaitBuffer(app.hndl,1000);
                            AT_QueueBuffer(app.hndl,imagesize);
                            [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                            database(:,:,i+1) = flip(transpose(buf2),2);
                        end
                        AT_Command(app.hndl,'AcquisitionStop');
                    else
                        AT_Command(app.hndl,'AcquisitionStart');
                        for i = 0:nFrames-1
                            [~,buf] = AT_WaitBuffer(app.hndl,1000);
                            AT_QueueBuffer(app.hndl,imagesize);
                            [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                            database(:,:,i+1) = flip(transpose(buf2),2);
                        end
                        AT_Command(app.hndl,'AcquisitionStop');
                    end
                    app.StreamLamp.Color = [0.9,0.9,0.9];
                    
                    % write database into disk
                    outputfile = [outputdir,'\XY_',num2str(nPos),'.tif'];
                    imwrite(database,outputfile,'Compression','None');
                end
                y_direction = -y_direction;
            end
            
            % turn off the white light
            write(app.dq,0);
            app.BFLamp.Color = [0.9,0.9,0.9];
            pause(0.1)
            AT_Flush(app.hndl);
            
            disp('Done.')
        end

        % Value changed function: pE_ACheckBox
        function pE_ACheckBoxValueChanged(app, event)
            value = app.pE_ACheckBox.Value;
            if value==1
                app.mmc.setProperty('pE4000','SelectionA',1)
                pE_A_wavelength=str2double(app.pE_ADropDown.Value);
                app.mmc.setProperty('pE4000','ChannelA',pE_A_wavelength)
                app.pE_ALamp.Color=[0.72,0.27,1.00];
            else
                app.mmc.setProperty('pE4000','SelectionA',0)
                app.pE_ALamp.Color=[0.90,0.90,0.90];
            end
            
            % update nFluos
            nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
            app.nFluosEditField.Value=num2str(nFluos);
            app.nFluoDropDown.Items = {};
            app.fluo_schedule = [];
            if (nFluos>0)
                app.nFluoDropDown.Visible = true;
                Items{1} = 'Choose...';
                for i = 2:nFluos+1
                    Items{i} = strcat('Fluo-',num2str(i-1));
                    app.fluo_schedule(i-1,:) = [0,0,1];
                end
                app.nFluoDropDown.Items = Items;
            else
                app.nFluoDropDown.Visible = false;
            end
        end

        % Value changed function: pE_BCheckBox
        function pE_BCheckBoxValueChanged(app, event)
            value = app.pE_BCheckBox.Value;
            if value==1
                app.mmc.setProperty('pE4000','SelectionB',1)
                pE_B_wavelength=str2double(app.pE_BDropDown.Value);
                app.mmc.setProperty('pE4000','ChannelB',pE_B_wavelength)
                app.pE_BLamp.Color=[0.07,0.62,1.00];
            else
                app.mmc.setProperty('pE4000','SelectionB',0)
                app.pE_BLamp.Color=[0.90,0.90,0.90];
            end
            
            
            % update nFluos
            nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
            app.nFluosEditField.Value=num2str(nFluos);
            app.nFluoDropDown.Items = {};
            app.fluo_schedule = [];
            if (nFluos>0)
                app.nFluoDropDown.Visible = true;
                Items{1} = 'Choose...';
                for i = 2:nFluos+1
                    Items{i} = strcat('Fluo-',num2str(i-1));
                    app.fluo_schedule(i-1,:) = [0,0,1];
                end
                app.nFluoDropDown.Items = Items;
            else
                app.nFluoDropDown.Visible = false;
            end
        end

        % Value changed function: pE_CCheckBox
        function pE_CCheckBoxValueChanged(app, event)
            value = app.pE_CCheckBox.Value;
            if value==1
                app.mmc.setProperty('pE4000','SelectionC',1)
                pE_C_wavelength=str2double(app.pE_CDropDown.Value);
                app.mmc.setProperty('pE4000','ChannelC',pE_C_wavelength)
                app.pE_CLamp.Color=[0.00,1.00,0.00];
            else
                app.mmc.setProperty('pE4000','SelectionC',0)
                app.pE_CLamp.Color=[0.90,0.90,0.90];
            end
            
            % update nFluos
            nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
            app.nFluosEditField.Value=num2str(nFluos);
            app.nFluoDropDown.Items = {};
            app.fluo_schedule = [];
            if (nFluos>0)
                app.nFluoDropDown.Visible = true;
                Items{1} = 'Choose...';
                for i = 2:nFluos+1
                    Items{i} = strcat('Fluo-',num2str(i-1));
                    app.fluo_schedule(i-1,:) = [0,0,1];
                end
                app.nFluoDropDown.Items = Items;
            else
                app.nFluoDropDown.Visible = false;
            end
        end

        % Value changed function: pE_DCheckBox
        function pE_DCheckBoxValueChanged(app, event)
            value = app.pE_DCheckBox.Value;
            if value==1
                app.mmc.setProperty('pE4000','SelectionD',1)
                pE_D_wavelength=str2double(app.pE_DDropDown.Value);
                app.mmc.setProperty('pE4000','ChannelD',pE_D_wavelength)
                app.pE_DLamp.Color=[1.00,0.00,0.00];
            else
                app.mmc.setProperty('pE4000','SelectionD',0)
                app.pE_DLamp.Color=[0.90,0.90,0.90];
            end
            
            % update nFluos
            nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
            app.nFluosEditField.Value=num2str(nFluos);
            app.nFluoDropDown.Items = {};
            app.fluo_schedule = [];
            if (nFluos>0)
                app.nFluoDropDown.Visible = true;
                Items{1} = 'Choose...';
                for i = 2:nFluos+1
                    Items{i} = strcat('Fluo-',num2str(i-1));
                    app.fluo_schedule(i-1,:) = [0,0,1];
                end
                app.nFluoDropDown.Items = Items;
            else
                app.nFluoDropDown.Visible = false;
            end
        end

        % Value changed function: pESwitch
        function pESwitchValueChanged(app, event)
            value = app.pESwitch.Value;
            if strcmp(value,'On')
                app.mmc.setProperty('pE4000','Global State',1)
            else
                app.mmc.setProperty('pE4000','Global State',0)
            end
        end

        % Value changed function: pE_BSpinner
        function pE_BSpinnerValueChanged(app, event)
            value = app.pE_BSpinner.Value;
            app.mmc.setProperty('pE4000','IntensityB',value)
        end

        % Value changed function: pEConnectButton
        function pEConnectButtonValueChanged(app, event)
            app.pEConnectButton.Enable=false;
            pause(0.1)
            value = app.pEConnectButton.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if (value==1)
                app.pEConnectButton.Text = 'Disconnect';
                app.pEConnectButton.BackgroundColor = [1,0,0];
                
                app.pESwitch.Enable=true;
                app.pE_ACheckBox.Enable=true;
                app.pE_BCheckBox.Enable=true;
                app.pE_CCheckBox.Enable=true;
                app.pE_DCheckBox.Enable=true;
                app.pE_ADropDown.Enable=true;
                app.pE_BDropDown.Enable=true;
                app.pE_CDropDown.Enable=true;
                app.pE_DDropDown.Enable=true;
                app.pE_ASpinner.Enable=true;
                app.pE_BSpinner.Enable=true;
                app.pE_CSpinner.Enable=true;
                app.pE_DSpinner.Enable=true;
                app.pE_KeepUpdateCheckBox.Enable=true;
                
                % create java library
                if app.ismmc==false
                    import mmcorej.*
                    app.mmc = CMMCore;
                    app.mmc.loadSystemConfiguration('MMConfig_TiE_XYZ.cfg');
                    app.ismmc=true;
                end
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Connect pE4000.'));
                app.HistoryTextArea.Value = flip(History,1);
                
                % ask current pE4000 configuration
                pE_A_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionA'));
                if pE_A_selectTF==1
                    app.pE_ACheckBox.Value=1;
                    app.pE_ALamp.Color=[0.72,0.27,1.00];
                else
                    app.pE_ACheckBox.Value=0;
                    app.pE_ALamp.Color=[0.90,0.90,0.90];
                end
                pE_A_wavelength=app.mmc.getProperty('pE4000','ChannelA');
                app.pE_ADropDown.Value=pE_A_wavelength;
                pE_A_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityA'));
                app.pE_ASpinner.Value=pE_A_Intensity;
                
                pE_B_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionB'));
                if pE_B_selectTF==1
                    app.pE_BCheckBox.Value=1;
                    app.pE_BLamp.Color=[0.07,0.62,1.00];
                else
                    app.pE_BCheckBox.Value=0;
                    app.pE_BLamp.Color=[0.90,0.90,0.90];
                end
                pE_B_wavelength=app.mmc.getProperty('pE4000','ChannelB');
                app.pE_BDropDown.Value=pE_B_wavelength;
                pE_B_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityB'));
                app.pE_BSpinner.Value=pE_B_Intensity;
                
                
                pE_C_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionC'));
                if pE_C_selectTF==1
                    app.pE_CCheckBox.Value=1;
                    app.pE_CLamp.Color=[0.00,1.00,0.00];
                else
                    app.pE_CCheckBox.Value=0;
                    app.pE_CLamp.Color=[0.90,0.90,0.90];
                end
                pE_C_wavelength=app.mmc.getProperty('pE4000','ChannelC');
                app.pE_CDropDown.Value=pE_C_wavelength;
                pE_C_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityC'));
                app.pE_CSpinner.Value=pE_C_Intensity;
                
                
                pE_D_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionD'));
                if pE_D_selectTF==1
                    app.pE_DCheckBox.Value=1;
                    app.pE_DLamp.Color=[1.00,0.00,0.00];
                else
                    app.pE_DCheckBox.Value=0;
                    app.pE_DLamp.Color=[0.90,0.90,0.90];
                end
                pE_D_wavelength=app.mmc.getProperty('pE4000','ChannelD');
                app.pE_DDropDown.Value=pE_D_wavelength;
                pE_D_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityD'));
                app.pE_DSpinner.Value=pE_D_Intensity;
                
                
                % update nFluos
                nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
                app.nFluosEditField.Value=num2str(nFluos);
                app.nFluoDropDown.Items = {};
                app.fluo_schedule = [];
                if (nFluos>0)
                    app.nFluoDropDown.Visible = true;
                    Items{1} = 'Choose...';
                    for i = 2:nFluos+1
                        Items{i} = strcat('Fluo-',num2str(i-1));
                        app.fluo_schedule(i-1,:) = [0,0,1];
                    end
                    app.nFluoDropDown.Items = Items;
                else
                    app.nFluoDropDown.Visible = false;
                end
                
            else
                app.pESwitch.Enable=false;
                app.pE_ACheckBox.Enable=false;
                app.pE_BCheckBox.Enable=false;
                app.pE_CCheckBox.Enable=false;
                app.pE_DCheckBox.Enable=false;
                app.pE_ADropDown.Enable=false;
                app.pE_BDropDown.Enable=false;
                app.pE_CDropDown.Enable=false;
                app.pE_DDropDown.Enable=false;
                app.pE_ASpinner.Enable=false;
                app.pE_BSpinner.Enable=false;
                app.pE_CSpinner.Enable=false;
                app.pE_DSpinner.Enable=false;
                app.pE_KeepUpdateCheckBox.Enable=false;
                
                % turn off global control
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Check whether turn off pE4000.'));
                app.HistoryTextArea.Value = flip(History,1);
                
                app.mmc.setProperty('pE4000','Global State',0)
                app.pESwitch.Value='Off';
                
                
                % reset mmc
                if app.StageConnectButton.Value==0
                    if app.pE_KeepUpdateCheckBox.Value==1
                        app.pE_KeepUpdateCheckBox.Value=0;
                        pause(1)
                        
                        nHistorys = size(History,1);
                        History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Disable to keep updating pE4000.'));
                        app.HistoryTextArea.Value = flip(History,1);
                    end
                    
                    app.mmc.reset()
                    app.ismmc=false;
                end
                app.pEConnectButton.Text = 'Connect';
                app.pEConnectButton.BackgroundColor = [0,1,0];
                
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Disonnect pE4000.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
            app.pEConnectButton.Enable=true;
        end

        % Value changed function: pE_ADropDown
        function pE_ADropDownValueChanged(app, event)
            value = app.pE_ADropDown.Value;
            app.mmc.setProperty('pE4000','ChannelA',value)
            if app.pE_ACheckBox.Value==0
                app.mmc.setProperty('pE4000','SelectionA',0)
            end
        end

        % Value changed function: pE_BDropDown
        function pE_BDropDownValueChanged(app, event)
            value = app.pE_BDropDown.Value;
            app.mmc.setProperty('pE4000','ChannelB',value)
            if app.pE_BCheckBox.Value==0
                app.mmc.setProperty('pE4000','SelectionB',0)
            end
        end

        % Value changed function: pE_CDropDown
        function pE_CDropDownValueChanged(app, event)
            value = app.pE_CDropDown.Value;
            app.mmc.setProperty('pE4000','ChannelC',value)
            if app.pE_CCheckBox.Value==0
                app.mmc.setProperty('pE4000','SelectionC',0)
            end
        end

        % Value changed function: pE_DDropDown
        function pE_DDropDownValueChanged(app, event)
            value = app.pE_DDropDown.Value;
            app.mmc.setProperty('pE4000','ChannelD',value)
            if app.pE_DCheckBox.Value==0
                app.mmc.setProperty('pE4000','SelectionD',0)
            end
        end

        % Value changed function: pE_ASpinner
        function pE_ASpinnerValueChanged(app, event)
            value = app.pE_ASpinner.Value;
            app.mmc.setProperty('pE4000','IntensityA',value)
        end

        % Value changed function: pE_CSpinner
        function pE_CSpinnerValueChanged(app, event)
            value = app.pE_CSpinner.Value;
            app.mmc.setProperty('pE4000','IntensityC',value)
        end

        % Value changed function: pE_DSpinner
        function pE_DSpinnerValueChanged(app, event)
            value = app.pE_DSpinner.Value;
            app.mmc.setProperty('pE4000','IntensityD',value)
        end

        % Selection changed function: FluoCubeButtonGroup
        function FluoCubeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FluoCubeButtonGroup.SelectedObject;
            FLC_idx=str2double(selectedButton.Text);
            if FLC_idx==6
                FLC_idx=0;
            end
            app.mmc.setProperty('TIFilterBlock1','State',FLC_idx);
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewButtonGroup.SelectedObject;
            FLC_idx=str2double(selectedButton.Text);
            app.mmc.setProperty('TILightPath','State',FLC_idx-1);
        end

        % Value changed function: pE_KeepUpdateCheckBox
        function pE_KeepUpdateCheckBoxValueChanged(app, event)
            value = app.pE_KeepUpdateCheckBox.Value;
            while (value==true)
                if (app.pE_KeepUpdateCheckBox.Value==false)
                    break;
                else
                    % ask current pE4000 configuration
                    pE_state=str2double(app.mmc.getProperty('pE4000','Global State'));
                    if pE_state==1
                        app.pESwitch.Value='On';
                    else
                        app.pESwitch.Value='Off';
                    end
                    
                    
                    pE_A_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionA'));
                    if pE_A_selectTF==1
                        app.pE_ACheckBox.Value=1;
                        app.pE_ALamp.Color=[0.72,0.27,1.00];
                    else
                        app.pE_ACheckBox.Value=0;
                        app.pE_ALamp.Color=[0.90,0.90,0.90];
                    end
                    pE_A_wavelength=app.mmc.getProperty('pE4000','ChannelA');
                    if (sum(str2double(app.pE_ADropDown.Items)==str2double(pE_A_wavelength))==1)
                        app.pE_ADropDown.Value=pE_A_wavelength;
                        pE_A_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityA'));
                        app.pE_ASpinner.Value=pE_A_Intensity;
                    end
                    
                    pE_B_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionB'));
                    if pE_B_selectTF==1
                        app.pE_BCheckBox.Value=1;
                        app.pE_BLamp.Color=[0.07,0.62,1.00];
                    else
                        app.pE_BCheckBox.Value=0;
                        app.pE_BLamp.Color=[0.90,0.90,0.90];
                    end
                    pE_B_wavelength=app.mmc.getProperty('pE4000','ChannelB');
                    if (sum(str2double(app.pE_BDropDown.Items)==str2double(pE_B_wavelength))==1)
                        app.pE_BDropDown.Value=pE_B_wavelength;
                        pE_B_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityB'));
                        app.pE_BSpinner.Value=pE_B_Intensity;
                    end
                    
                    pE_C_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionC'));
                    if pE_C_selectTF==1
                        app.pE_CCheckBox.Value=1;
                        app.pE_CLamp.Color=[0.00,1.00,0.00];
                    else
                        app.pE_CCheckBox.Value=0;
                        app.pE_CLamp.Color=[0.90,0.90,0.90];
                    end
                    pE_C_wavelength=app.mmc.getProperty('pE4000','ChannelC');
                    if (sum(str2double(app.pE_CDropDown.Items)==str2double(pE_C_wavelength))==1)
                        app.pE_CDropDown.Value=pE_C_wavelength;
                        pE_C_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityC'));
                        app.pE_CSpinner.Value=pE_C_Intensity;
                    end
                    
                    pE_D_selectTF=str2double(app.mmc.getProperty('pE4000','SelectionD'));
                    if pE_D_selectTF==1
                        app.pE_DCheckBox.Value=1;
                        app.pE_DLamp.Color=[1.00,0.00,0.00];
                    else
                        app.pE_DCheckBox.Value=0;
                        app.pE_DLamp.Color=[0.90,0.90,0.90];
                    end
                    pE_D_wavelength=app.mmc.getProperty('pE4000','ChannelD');
                    if (sum(str2double(app.pE_DDropDown.Items)==str2double(pE_D_wavelength))==1)
                        app.pE_DDropDown.Value=pE_D_wavelength;
                        pE_D_Intensity=str2double(app.mmc.getProperty('pE4000','IntensityD'));
                        app.pE_DSpinner.Value=pE_D_Intensity;
                    end
                    
                    
                    % update nFluos
                    nFluos=app.pE_ACheckBox.Value+app.pE_BCheckBox.Value+app.pE_CCheckBox.Value+app.pE_DCheckBox.Value;
                    app.nFluosEditField.Value=num2str(nFluos);
                    app.nFluoDropDown.Items = {};
                    app.fluo_schedule = [];
                    if (nFluos>0)
                        app.nFluoDropDown.Visible = true;
                        Items{1} = 'Choose...';
                        for i = 2:nFluos+1
                            Items{i} = strcat('Fluo-',num2str(i-1));
                            app.fluo_schedule(i-1,:) = [0,0,1];
                        end
                        app.nFluoDropDown.Items = Items;
                    else
                        app.nFluoDropDown.Visible = false;
                    end
                    
                    % simultaneously update TiE
                    if (app.XYZUpdateCheckBox.Value==1)
                        x = app.mmc.getXPosition();
                        y = app.mmc.getYPosition();
                        z = app.mmc.getPosition();
                        pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
                        if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                            app.PFSLamp.Color = [0,1,0];
                            app.PFSSwitch.Value = 'On';
                        else
                            app.PFSLamp.Color = [0.9,0.9,0.9];
                            app.PFSSwitch.Value = 'Off';
                        end
                        app.XumEditField.Value = x;
                        app.YumEditField.Value = y;
                        app.ZumEditField.Value = z;
                        app.PFSEditField.Value = pfs_z;
                        
                        %
                        FLC_idx=str2double(app.mmc.getProperty('TIFilterBlock1','State'));
                        switch FLC_idx
                            case 1
                                app.FLC_Button_1.Value=1;
                            case 2
                                app.FLC_Button_2.Value=1;
                            case 3
                                app.FLC_Button_3.Value=1;
                            case 4
                                app.FLC_Button_4.Value=1;
                            case 5
                                app.FLC_Button_5.Value=1;
                            case 0
                                app.FLC_Button_6.Value=1;
                        end
                        
                        %
                        view_port=str2double(app.mmc.getProperty('TILightPath','State'));
                        switch view_port
                            case 0
                                app.EyeButton_6.Value=1;
                            case 1
                                app.L100Button2.Value=1;
                            case 2
                                app.R100Button3.Value=1;
                        end
                    end
                    
                    pause(0.05)
                end
            end
        end

        % Value changed function: nFluoDropDown
        function nFluoDropDownValueChanged(app, event)
            value = app.nFluoDropDown.Value;
            for i = 1:str2double(app.nFluosEditField.Value)
                if strcmp(strcat('Fluo-',num2str(i)),value)
                    nFluo = i;
                end
            end
            
            prompt = {'\fontsize{16} Enter index of fluorescence cube:','\fontsize{16} Enter exposure time [msec]:','\fontsize{16} Enter frames of fluorescence:'};
            dlgtitle = 'Input';
            dims = [1 66];
            definput = {num2str(app.fluo_schedule(nFluo,1)), num2str(app.fluo_schedule(nFluo,2)),num2str(app.fluo_schedule(nFluo,3))};
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt,dlgtitle,dims,definput,opts);
            if ~isempty(answer)
                if round(str2double(answer{3}))>app.nFramesEditField.Value
                    app.fluo_schedule(nFluo,:) = [str2double(answer{1}),str2double(answer{2}),app.nFramesEditField.Value];
                elseif round(str2double(answer{3}))<=0
                    app.fluo_schedule(nFluo,:) = [str2double(answer{1}),str2double(answer{2}),1];
                else
                    app.fluo_schedule(nFluo,:) = [str2double(answer{1}),str2double(answer{2}),round(str2double(answer{3}))];
                end
                
            end
            app.fluo_schedule
            app.nFluoDropDown.Value = 'Choose...';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SavefiletoPanel = uipanel(app.UIFigure);
            app.SavefiletoPanel.Title = 'Save file to...';
            app.ChooseButton = uibutton(app.SavefiletoPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.OpenButton = uibutton(app.SavefiletoPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.FolderpathEditFieldLabel = uilabel(app.SavefiletoPanel);
            app.FolderpathEditFieldLabel.Text = 'Folder path:';
            app.FolderpathEditField = uieditfield(app.SavefiletoPanel, 'text');
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Camera_Zyla';
            app.StreamLampLabel = uilabel(app.CameraPanel);
            app.StreamLampLabel.Text = 'Stream';
            app.StreamLamp = uilamp(app.CameraPanel);
            app.PreviewSwitch = uiswitch(app.CameraPanel, 'slider');
            app.PreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @PreviewSwitchValueChanged, true);
            app.PreviewSwitch.Enable = 'off';
            app.PreviewSwitch.Tooltip = {'Preview?'};
            app.CamConnectButton = uibutton(app.CameraPanel, 'state');
            app.CamConnectButton.ValueChangedFcn = createCallbackFcn(app, @CamConnectButtonValueChanged, true);
            app.CamConnectButton.Text = 'Connect';
            app.ExposuretimemsecEditFieldLabel = uilabel(app.CameraPanel);
            app.ExposuretimemsecEditFieldLabel.Text = 'Exposure time [msec]';
            app.ExposuretimemsecEditField = uieditfield(app.CameraPanel, 'numeric');
            app.ExposuretimemsecEditField.ValueDisplayFormat = '%.3f';
            app.ExposuretimemsecEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimemsecEditFieldValueChanged, true);
            app.ExposuretimemsecEditField.Enable = 'off';
            app.ExposuretimemsecEditField.Tooltip = {'The unit is msec.'};
            app.ExposuretimemsecEditField.Value = 30;
            app.GainmodeDropDownLabel = uilabel(app.CameraPanel);
            app.GainmodeDropDownLabel.Text = 'Gain mode';
            app.GainmodeDropDown = uidropdown(app.CameraPanel);
            app.GainmodeDropDown.Items = {'12-bit (high well capacity)', '12-bit (low noise)', '16-bit (low noise & high well capacity)'};
            app.GainmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @GainmodeDropDownValueChanged, true);
            app.GainmodeDropDown.Enable = 'off';
            app.GainmodeDropDown.Value = '12-bit (high well capacity)';
            app.TriggermodeDropDownLabel = uilabel(app.CameraPanel);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.TriggermodeDropDown = uidropdown(app.CameraPanel);
            app.TriggermodeDropDown.Items = {'Internal', 'External Level Transition', 'External Start', 'External Exposure', 'Software', 'Advanced', 'External'};
            app.TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggermodeDropDownValueChanged, true);
            app.TriggermodeDropDown.Enable = 'off';
            app.TriggermodeDropDown.Value = 'Internal';
            app.SpuriousNoiseFilterCheckBox = uicheckbox(app.CameraPanel);
            app.SpuriousNoiseFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpuriousNoiseFilterCheckBoxValueChanged, true);
            app.SpuriousNoiseFilterCheckBox.Enable = 'off';
            app.SpuriousNoiseFilterCheckBox.Text = 'SpuriousNoiseFilter';
            app.SpuriousNoiseFilterCheckBox.Value = true;
            app.TemperatureCEditFieldLabel = uilabel(app.CameraPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature [C]';
            app.TemperatureCEditField = uieditfield(app.CameraPanel, 'numeric');
            app.TemperatureCEditField.ValueDisplayFormat = '%.2f';
            app.TemperatureCEditField.Editable = 'off';
            app.TemperatureCEditField.Enable = 'off';
            app.TemperatureCEditField.Tooltip = {'The unit is degree Celsius.'};
            app.FramepersecondHzEditFieldLabel = uilabel(app.CameraPanel);
            app.FramepersecondHzEditFieldLabel.Text = 'Frame per second [Hz]';
            app.FramepersecondHzEditField = uieditfield(app.CameraPanel, 'numeric');
            app.FramepersecondHzEditField.Editable = 'off';
            app.FramepersecondHzEditField.Enable = 'off';
            app.ReadoutmodeDropDownLabel = uilabel(app.CameraPanel);
            app.ReadoutmodeDropDownLabel.Text = 'Readout mode';
            app.ReadoutmodeDropDown = uidropdown(app.CameraPanel);
            app.ReadoutmodeDropDown.Items = {'10 MHz', '100 MHz', '200 MHz', '270 MHz'};
            app.ReadoutmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @ReadoutmodeDropDownValueChanged, true);
            app.ReadoutmodeDropDown.Enable = 'off';
            app.ReadoutmodeDropDown.Value = '10 MHz';
            app.CenterCheckBox = uicheckbox(app.CameraPanel);
            app.CenterCheckBox.ValueChangedFcn = createCallbackFcn(app, @CenterCheckBoxValueChanged, true);
            app.CenterCheckBox.Enable = 'off';
            app.CenterCheckBox.Text = 'Center';
            app.CenterCheckBox.Value = true;
            app.WidthEditFieldLabel = uilabel(app.CameraPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.CameraPanel, 'numeric');
            app.WidthEditField.ValueDisplayFormat = '%.0f';
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Enable = 'off';
            app.WidthEditField.Value = 2048;
            app.HeightEditFieldLabel = uilabel(app.CameraPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.CameraPanel, 'numeric');
            app.HeightEditField.ValueDisplayFormat = '%.0f';
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Enable = 'off';
            app.HeightEditField.Value = 2048;
            app.UpdateButton = uibutton(app.CameraPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.OverlapCheckBox = uicheckbox(app.CameraPanel);
            app.OverlapCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlapCheckBoxValueChanged, true);
            app.OverlapCheckBox.Enable = 'off';
            app.OverlapCheckBox.Text = 'Overlap';
            app.OverlapCheckBox.Value = true;
            app.AcquisitionsettingPanel = uipanel(app.UIFigure);
            app.AcquisitionsettingPanel.Title = 'Acquisition setting';
            app.StartAcquiButton = uibutton(app.AcquisitionsettingPanel, 'push');
            app.StartAcquiButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquiButtonPushed, true);
            app.StartAcquiButton.Enable = 'off';
            app.StartAcquiButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.AcquisitionsettingPanel);
            app.StarvationTab = uitab(app.TabGroup);
            app.StarvationTab.Title = 'Starvation';
            app.nFramesEditFieldLabel = uilabel(app.StarvationTab);
            app.nFramesEditFieldLabel.Text = 'nFrames';
            app.nFramesEditField = uieditfield(app.StarvationTab, 'numeric');
            app.nFramesEditField.ValueDisplayFormat = '%.0f';
            app.nFramesEditField.Tooltip = {'The number of fast-timelapse for BF..'};
            app.nFramesEditField.Value = 100;
            app.nLoopsEditFieldLabel = uilabel(app.StarvationTab);
            app.nLoopsEditFieldLabel.Text = 'nLoops';
            app.nLoopsEditField = uieditfield(app.StarvationTab, 'numeric');
            app.nLoopsEditField.Editable = 'off';
            app.nLoopsEditField.Tooltip = {''};
            app.nTimerDropDown = uidropdown(app.StarvationTab);
            app.nTimerDropDown.Items = {};
            app.nTimerDropDown.ValueChangedFcn = createCallbackFcn(app, @nTimerDropDownValueChanged, true);
            app.nTimerDropDown.Visible = 'off';
            app.nTimerDropDown.Tooltip = {''};
            app.nTimerDropDown.Value = {};
            app.nTimersEditFieldLabel = uilabel(app.StarvationTab);
            app.nTimersEditFieldLabel.Text = 'nTimers';
            app.nTimersEditField = uieditfield(app.StarvationTab, 'numeric');
            app.nTimersEditField.ValueDisplayFormat = '%.0f';
            app.nTimersEditField.ValueChangedFcn = createCallbackFcn(app, @nTimersEditFieldValueChanged, true);
            app.nTimersEditField.Tooltip = {'The number of timer.'};
            app.CorrectXYCheckBox = uicheckbox(app.StarvationTab);
            app.CorrectXYCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectXYCheckBoxValueChanged, true);
            app.CorrectXYCheckBox.Tooltip = {'Correct drifting XY to  increase stage stability.'};
            app.CorrectXYCheckBox.Text = 'Correct XY?';
            app.CorrectZCheckBox = uicheckbox(app.StarvationTab);
            app.CorrectZCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectZCheckBoxValueChanged, true);
            app.CorrectZCheckBox.Text = 'Correct Z?';
            app.nFluosEditFieldLabel = uilabel(app.StarvationTab);
            app.nFluosEditFieldLabel.Text = 'nFluos';
            app.nFluosEditField = uieditfield(app.StarvationTab, 'text');
            app.nFluosEditField.Editable = 'off';
            app.nFluosEditField.Value = '0';
            app.nFluoDropDown = uidropdown(app.StarvationTab);
            app.nFluoDropDown.Items = {};
            app.nFluoDropDown.ValueChangedFcn = createCallbackFcn(app, @nFluoDropDownValueChanged, true);
            app.nFluoDropDown.Visible = 'off';
            app.nFluoDropDown.Value = {};
            app.MagEditFieldLabel = uilabel(app.StarvationTab);
            app.MagEditFieldLabel.Text = 'Mag';
            app.MagEditField = uieditfield(app.StarvationTab, 'numeric');
            app.MagEditField.Limits = [1 Inf];
            app.MagEditField.Tooltip = {'Overall magnification'};
            app.MagEditField.Value = 60;
            app.ScanXYTab = uitab(app.TabGroup);
            app.ScanXYTab.Title = 'ScanXY';
            app.Start2Button = uibutton(app.ScanXYTab, 'push');
            app.Start2Button.ButtonPushedFcn = createCallbackFcn(app, @Start2ButtonPushed, true);
            app.Start2Button.Text = 'Start2';
            app.ThispartistestingLabel = uilabel(app.ScanXYTab);
            app.ThispartistestingLabel.Text = 'This part is testing,...';
            app.ApplyButton = uibutton(app.AcquisitionsettingPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.HistoryPanel = uipanel(app.UIFigure);
            app.HistoryPanel.Title = 'History';
            app.HistoryTextArea = uitextarea(app.HistoryPanel);
            app.HistoryTextArea.Value = {'Have a nice day!'};
            app.LightSourcePanel = uipanel(app.UIFigure);
            app.LightSourcePanel.Title = 'Light Source';
            app.WhitelightPanel = uipanel(app.LightSourcePanel);
            app.WhitelightPanel.Title = 'White light';
            app.BFSwitch = uiswitch(app.WhitelightPanel, 'rocker');
            app.BFSwitch.ValueChangedFcn = createCallbackFcn(app, @BFSwitchValueChanged, true);
            app.BFSwitch.Enable = 'off';
            app.BFLamp = uilamp(app.WhitelightPanel);
            app.BFConnectButton = uibutton(app.WhitelightPanel, 'state');
            app.BFConnectButton.ValueChangedFcn = createCallbackFcn(app, @BFConnectButtonValueChanged, true);
            app.BFConnectButton.Text = 'Connect';
            app.daqDevEditField = uieditfield(app.WhitelightPanel, 'text');
            app.daqDevEditField.Editable = 'off';
            app.daqDevEditField.Value = 'Dev2';
            app.daqAOEditField = uieditfield(app.WhitelightPanel, 'text');
            app.daqAOEditField.Editable = 'off';
            app.daqAOEditField.Value = 'ao0';
            app.DAQLabel = uilabel(app.WhitelightPanel);
            app.DAQLabel.Text = 'DAQ';
            app.CoolLEDPanel = uipanel(app.LightSourcePanel);
            app.CoolLEDPanel.Title = 'CoolLED';
            app.pE_ALamp = uilamp(app.CoolLEDPanel);
            app.pE_BLamp = uilamp(app.CoolLEDPanel);
            app.pE_CLamp = uilamp(app.CoolLEDPanel);
            app.pE_DLamp = uilamp(app.CoolLEDPanel);
            app.pEConnectButton = uibutton(app.CoolLEDPanel, 'state');
            app.pEConnectButton.ValueChangedFcn = createCallbackFcn(app, @pEConnectButtonValueChanged, true);
            app.pEConnectButton.Text = 'Connect';
            app.pE_ACheckBox = uicheckbox(app.CoolLEDPanel);
            app.pE_ACheckBox.ValueChangedFcn = createCallbackFcn(app, @pE_ACheckBoxValueChanged, true);
            app.pE_ACheckBox.Enable = 'off';
            app.pE_ACheckBox.Text = '';
            app.pE_BCheckBox = uicheckbox(app.CoolLEDPanel);
            app.pE_BCheckBox.ValueChangedFcn = createCallbackFcn(app, @pE_BCheckBoxValueChanged, true);
            app.pE_BCheckBox.Enable = 'off';
            app.pE_BCheckBox.Text = '';
            app.pE_CCheckBox = uicheckbox(app.CoolLEDPanel);
            app.pE_CCheckBox.ValueChangedFcn = createCallbackFcn(app, @pE_CCheckBoxValueChanged, true);
            app.pE_CCheckBox.Enable = 'off';
            app.pE_CCheckBox.Text = '';
            app.pE_DCheckBox = uicheckbox(app.CoolLEDPanel);
            app.pE_DCheckBox.ValueChangedFcn = createCallbackFcn(app, @pE_DCheckBoxValueChanged, true);
            app.pE_DCheckBox.Enable = 'off';
            app.pE_DCheckBox.Text = '';
            app.pE_ADropDown = uidropdown(app.CoolLEDPanel);
            app.pE_ADropDown.Items = {'365', '385', '405', '435'};
            app.pE_ADropDown.ValueChangedFcn = createCallbackFcn(app, @pE_ADropDownValueChanged, true);
            app.pE_ADropDown.Enable = 'off';
            app.pE_ADropDown.Value = '405';
            app.pE_BDropDown = uidropdown(app.CoolLEDPanel);
            app.pE_BDropDown.Items = {'460', '470', '490', '500'};
            app.pE_BDropDown.ValueChangedFcn = createCallbackFcn(app, @pE_BDropDownValueChanged, true);
            app.pE_BDropDown.Enable = 'off';
            app.pE_BDropDown.Value = '470';
            app.pE_CDropDown = uidropdown(app.CoolLEDPanel);
            app.pE_CDropDown.Items = {'525', '550', '580', '595'};
            app.pE_CDropDown.ValueChangedFcn = createCallbackFcn(app, @pE_CDropDownValueChanged, true);
            app.pE_CDropDown.Enable = 'off';
            app.pE_CDropDown.Value = '525';
            app.pE_DDropDown = uidropdown(app.CoolLEDPanel);
            app.pE_DDropDown.Items = {'635', '660', '740', '770'};
            app.pE_DDropDown.ValueChangedFcn = createCallbackFcn(app, @pE_DDropDownValueChanged, true);
            app.pE_DDropDown.Enable = 'off';
            app.pE_DDropDown.Value = '635';
            app.pE_ASpinner = uispinner(app.CoolLEDPanel);
            app.pE_ASpinner.Limits = [0 100];
            app.pE_ASpinner.ValueChangedFcn = createCallbackFcn(app, @pE_ASpinnerValueChanged, true);
            app.pE_ASpinner.Enable = 'off';
            app.pE_BSpinner = uispinner(app.CoolLEDPanel);
            app.pE_BSpinner.Limits = [0 100];
            app.pE_BSpinner.ValueChangedFcn = createCallbackFcn(app, @pE_BSpinnerValueChanged, true);
            app.pE_BSpinner.Enable = 'off';
            app.pE_CSpinner = uispinner(app.CoolLEDPanel);
            app.pE_CSpinner.Limits = [0 100];
            app.pE_CSpinner.ValueChangedFcn = createCallbackFcn(app, @pE_CSpinnerValueChanged, true);
            app.pE_CSpinner.Enable = 'off';
            app.pE_DSpinner = uispinner(app.CoolLEDPanel);
            app.pE_DSpinner.Limits = [0 100];
            app.pE_DSpinner.ValueChangedFcn = createCallbackFcn(app, @pE_DSpinnerValueChanged, true);
            app.pE_DSpinner.Enable = 'off';
            app.pESwitch = uiswitch(app.CoolLEDPanel, 'toggle');
            app.pESwitch.ValueChangedFcn = createCallbackFcn(app, @pESwitchValueChanged, true);
            app.pESwitch.Enable = 'off';
            app.ExnmLabel = uilabel(app.CoolLEDPanel);
            app.ExnmLabel.Text = 'Ex [nm]';
            app.PowerLabel = uilabel(app.CoolLEDPanel);
            app.PowerLabel.Text = 'Power [%]';
            app.UseLabel = uilabel(app.CoolLEDPanel);
            app.UseLabel.Text = 'Use?';
            app.pE_KeepUpdateCheckBox = uicheckbox(app.CoolLEDPanel);
            app.pE_KeepUpdateCheckBox.ValueChangedFcn = createCallbackFcn(app, @pE_KeepUpdateCheckBoxValueChanged, true);
            app.pE_KeepUpdateCheckBox.Enable = 'off';
            app.pE_KeepUpdateCheckBox.Tooltip = {'Keep updating control panel of pE4000.'};
            app.pE_KeepUpdateCheckBox.Text = 'Keep updating';
            app.NikonTiEPanel = uipanel(app.UIFigure);
            app.NikonTiEPanel.Title = 'Nikon TiE';
            app.MotorizedStagePanel = uipanel(app.NikonTiEPanel);
            app.MotorizedStagePanel.Title = 'Motorized Stage';
            app.StageConnectButton = uibutton(app.MotorizedStagePanel, 'state');
            app.StageConnectButton.ValueChangedFcn = createCallbackFcn(app, @StageConnectButtonValueChanged, true);
            app.StageConnectButton.Text = 'Connect';
            app.XumLabel = uilabel(app.MotorizedStagePanel);
            app.XumLabel.Text = 'X [um]';
            app.XumEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.XumEditField.ValueDisplayFormat = '%.2f';
            app.XumEditField.Editable = 'off';
            app.XumEditField.Tooltip = {'The unit is micron.'};
            app.YumEditFieldLabel = uilabel(app.MotorizedStagePanel);
            app.YumEditFieldLabel.Text = 'Y [um]';
            app.YumEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.YumEditField.ValueDisplayFormat = '%.2f';
            app.YumEditField.Editable = 'off';
            app.YumEditField.Tooltip = {'The unit is micron.'};
            app.ZumEditFieldLabel = uilabel(app.MotorizedStagePanel);
            app.ZumEditFieldLabel.Text = 'Z [um]';
            app.ZumEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.ZumEditField.ValueDisplayFormat = '%.2f';
            app.ZumEditField.Editable = 'off';
            app.ZumEditField.Tooltip = {'The unit is micron.'};
            app.PFSEditFieldLabel = uilabel(app.MotorizedStagePanel);
            app.PFSEditFieldLabel.Text = 'PFS';
            app.PFSEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.PFSEditField.ValueDisplayFormat = '%.2f';
            app.PFSEditField.Editable = 'off';
            app.AddButton = uibutton(app.MotorizedStagePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Tooltip = {'Add a region of interest.'};
            app.AddButton.Text = 'Add';
            app.PFSLampLabel = uilabel(app.MotorizedStagePanel);
            app.PFSLampLabel.Text = 'PFS';
            app.PFSLamp = uilamp(app.MotorizedStagePanel);
            app.PFSSwitch = uiswitch(app.MotorizedStagePanel, 'slider');
            app.PFSSwitch.ValueChangedFcn = createCallbackFcn(app, @PFSSwitchValueChanged, true);
            app.PFSSwitch.Enable = 'off';
            app.ListButton = uibutton(app.MotorizedStagePanel, 'push');
            app.ListButton.ButtonPushedFcn = createCallbackFcn(app, @ListButtonPushed, true);
            app.ListButton.Tooltip = {'Show information of the  selected ROI.'};
            app.ListButton.Text = 'List';
            app.roiCountEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.roiCountEditField.ValueDisplayFormat = '%.0f';
            app.roiCountEditField.Editable = 'off';
            app.roiCountEditField.Tooltip = {'The number of selected ROI.'};
            app.XYZUpdateCheckBox = uicheckbox(app.MotorizedStagePanel);
            app.XYZUpdateCheckBox.ValueChangedFcn = createCallbackFcn(app, @XYZUpdateCheckBoxValueChanged, true);
            app.XYZUpdateCheckBox.Enable = 'off';
            app.XYZUpdateCheckBox.Tooltip = {'Keep updating TiE''s information.'};
            app.XYZUpdateCheckBox.Text = 'Keep updating';
            app.LightPathPanel = uipanel(app.NikonTiEPanel);
            app.LightPathPanel.Title = 'LightPath';
            app.FluoCubeButtonGroup = uibuttongroup(app.LightPathPanel);
            app.FluoCubeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FluoCubeButtonGroupSelectionChanged, true);
            app.FluoCubeButtonGroup.Title = 'FluoCube';
            app.FLC_Button_1 = uitogglebutton(app.FluoCubeButtonGroup);
            app.FLC_Button_1.Enable = 'off';
            app.FLC_Button_1.Text = '1';
            app.FLC_Button_1.Value = true;
            app.FLC_Button_2 = uitogglebutton(app.FluoCubeButtonGroup);
            app.FLC_Button_2.Enable = 'off';
            app.FLC_Button_2.Text = '2';
            app.FLC_Button_3 = uitogglebutton(app.FluoCubeButtonGroup);
            app.FLC_Button_3.Enable = 'off';
            app.FLC_Button_3.Text = '3';
            app.FLC_Button_4 = uitogglebutton(app.FluoCubeButtonGroup);
            app.FLC_Button_4.Enable = 'off';
            app.FLC_Button_4.Text = '4';
            app.FLC_Button_5 = uitogglebutton(app.FluoCubeButtonGroup);
            app.FLC_Button_5.Enable = 'off';
            app.FLC_Button_5.Text = '5';
            app.FLC_Button_6 = uitogglebutton(app.FluoCubeButtonGroup);
            app.FLC_Button_6.Enable = 'off';
            app.FLC_Button_6.Text = '6';
            app.ViewButtonGroup = uibuttongroup(app.LightPathPanel);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.EyeButton_6 = uiradiobutton(app.ViewButtonGroup);
            app.EyeButton_6.Enable = 'off';
            app.EyeButton_6.Text = '1';
            app.EyeButton_6.Value = true;
            app.L100Button2 = uiradiobutton(app.ViewButtonGroup);
            app.L100Button2.Enable = 'off';
            app.L100Button2.Text = '2';
            app.R100Button3 = uiradiobutton(app.ViewButtonGroup);
            app.R100Button3.Enable = 'off';
            app.R100Button3.Text = '3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NikonTiE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end