classdef NikonTiE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SavefiletoPanel               matlab.ui.container.Panel
        ChooseButton                  matlab.ui.control.Button
        OpenButton                    matlab.ui.control.Button
        FolderpathEditFieldLabel      matlab.ui.control.Label
        FolderpathEditField           matlab.ui.control.EditField
        WhitelightPanel               matlab.ui.container.Panel
        BFSwitch                      matlab.ui.control.RockerSwitch
        BFLampLabel                   matlab.ui.control.Label
        BFLamp                        matlab.ui.control.Lamp
        BFConnectButton               matlab.ui.control.StateButton
        CameraPanel                   matlab.ui.container.Panel
        StreamLampLabel               matlab.ui.control.Label
        StreamLamp                    matlab.ui.control.Lamp
        PreviewSwitch                 matlab.ui.control.Switch
        CamConnectButton              matlab.ui.control.StateButton
        ExposuretimeEditFieldLabel    matlab.ui.control.Label
        ExposuretimeEditField         matlab.ui.control.NumericEditField
        GainmodeDropDownLabel         matlab.ui.control.Label
        GainmodeDropDown              matlab.ui.control.DropDown
        TriggermodeDropDownLabel      matlab.ui.control.Label
        TriggermodeDropDown           matlab.ui.control.DropDown
        SpuriousNoiseFilterCheckBox   matlab.ui.control.CheckBox
        TemperatureEditFieldLabel     matlab.ui.control.Label
        TemperatureEditField          matlab.ui.control.NumericEditField
        FramepersecondEditFieldLabel  matlab.ui.control.Label
        FramepersecondEditField       matlab.ui.control.NumericEditField
        ReadoutmodeDropDownLabel      matlab.ui.control.Label
        ReadoutmodeDropDown           matlab.ui.control.DropDown
        CenterCheckBox                matlab.ui.control.CheckBox
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        UpdateButton                  matlab.ui.control.Button
        OverlapCheckBox               matlab.ui.control.CheckBox
        MotorizedStagePanel           matlab.ui.container.Panel
        StageConnectButton            matlab.ui.control.StateButton
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        PFSEditFieldLabel             matlab.ui.control.Label
        PFSEditField                  matlab.ui.control.NumericEditField
        AddButton                     matlab.ui.control.Button
        PFSLampLabel                  matlab.ui.control.Label
        PFSLamp                       matlab.ui.control.Lamp
        PFSSwitch                     matlab.ui.control.Switch
        ListButton                    matlab.ui.control.Button
        roiCountEditField             matlab.ui.control.NumericEditField
        XYZUpdateCheckBox             matlab.ui.control.CheckBox
        AcquisitionsettingPanel       matlab.ui.container.Panel
        StartAcquiButton              matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        StarvationTab                 matlab.ui.container.Tab
        nFramesEditFieldLabel         matlab.ui.control.Label
        nFramesEditField              matlab.ui.control.NumericEditField
        nLoopsEditFieldLabel          matlab.ui.control.Label
        nLoopsEditField               matlab.ui.control.NumericEditField
        nTimerDropDown                matlab.ui.control.DropDown
        nTimersEditFieldLabel         matlab.ui.control.Label
        nTimersEditField              matlab.ui.control.NumericEditField
        CorrectXYCheckBox             matlab.ui.control.CheckBox
        CorrectZCheckBox              matlab.ui.control.CheckBox
        LargeXYTab                    matlab.ui.container.Tab
        Start2Button                  matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        HistoryPanel                  matlab.ui.container.Panel
        HistoryTextArea               matlab.ui.control.TextArea
    end

    properties (Access = public)
        hndl;
        mmc;
        dq;
        hFig;
        hImage;
        schedule;
        schedule_timer;
        listing_pos = [];
        database0 = [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            selpath = uigetdir('F:\');
            History = flip(app.HistoryTextArea.Value,1);
            
            if ischar(selpath)
                app.FolderpathEditField.Value = selpath;
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',selpath,'" as the current working folder.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            selpath = app.FolderpathEditField.Value;
            History = flip(app.HistoryTextArea.Value,1);
            
            if ~isempty(selpath)
                winopen(selpath);
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Open the current working folder.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: BFConnectButton
        function BFConnectButtonValueChanged(app, event)
            value = app.BFConnectButton.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if (value==1)
                app.BFSwitch.Enable = true;
                app.BFConnectButton.Text = 'Disconnect';
                app.BFConnectButton.BackgroundColor = [1,0,0];
                
                % create ni daq session and output channel
                app.dq = daq("ni");
                addoutput(app.dq, "Dev2", "ao0", "Voltage");
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Connect NI-DAQ.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                app.BFSwitch.Enable = false;
                app.BFConnectButton.Text = 'Connect';
                app.BFConnectButton.BackgroundColor = [0,1,0];
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Disconnect NI-DAQ.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: BFSwitch
        function BFSwitchValueChanged(app, event)
            value = app.BFSwitch.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if strcmp(value,'On')
                app.BFLamp.Color = [0,1,0];
                
                % write command to daq
                write(app.dq,5);
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn on BF.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                app.BFLamp.Color = [0.9,0.9,0.9];
                
                % write command to daq
                write(app.dq,0);
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn off BF.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: CamConnectButton
        function CamConnectButtonValueChanged(app, event)
            value = app.CamConnectButton.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if (value==1)
                app.CamConnectButton.Text = 'Disconnect';
                app.CamConnectButton.BackgroundColor = [1,0,0];
                app.PreviewSwitch.Enable = true;
                app.TemperatureEditField.Enable = true;
                app.FramepersecondEditField.Enable = true;
                app.ExposuretimeEditField.Enable = true;
                app.ExposuretimeEditField.Value = 30;
                app.GainmodeDropDown.Enable = true;
                app.GainmodeDropDown.Value = '11-bit (high well capacity)';
                app.TriggermodeDropDown.Enable = true;
                app.TriggermodeDropDown.Value = 'Internal';
                app.ReadoutmodeDropDown.Enable = true;
                app.ReadoutmodeDropDown.Value = '10 MHz';
                app.WidthEditField.Enable = true;
                app.WidthEditField.Value = 2560;
                app.HeightEditField.Enable = true;
                app.HeightEditField.Value = 2160;
                app.SpuriousNoiseFilterCheckBox.Enable = true;
                app.SpuriousNoiseFilterCheckBox.Value = 1;
                app.OverlapCheckBox.Enable = true;
                app.OverlapCheckBox.Value = 1;
                app.CenterCheckBox.Enable = true;
                app.CenterCheckBox.Value = 1;
                
                % create camera session
                AT_InitialiseLibrary();
                [~,app.hndl] = AT_Open(0);
                
                % set cooling and get current temperature
                AT_SetBool(app.hndl,'SensorCooling',1);                                         % turn on/off cooling
                %                 [~,cooling] = AT_GetBool(app.hndl,'SensorCooling');                          	% get cooling status
                %                 [~,currentIndex] = AT_GetEnumIndex(app.hndl,'TemperatureStatus');            	% get temperature status [0-cooler off, 1-stable, 2-cooling, 3-drift, 4-not stable, 5-Fault]
                [~,SensorTemp] = AT_GetFloat(app.hndl,'SensorTemperature');                     % get sensor temperaure
                app.TemperatureEditField.Value = SensorTemp;
                
                % get camera current setting
                % Overlap
                [~,bool] = AT_GetBool(app.hndl,'Overlap');
                if bool==1
                    app.OverlapCheckBox.Value = true;
                else
                    app.OverlapCheckBox.Value = false;
                end
                
                % SpuriousNoiseFilter
                [~,bool] = AT_GetBool(app.hndl,'SpuriousNoiseFilter');
                if bool==1
                    app.SpuriousNoiseFilterCheckBox.Value = true;
                else
                    app.SpuriousNoiseFilterCheckBox.Value = false;
                end
                
                % Center ROI
                [~,bool] = AT_GetBool(app.hndl,'VerticallyCentreAOI');
                if bool==1
                    app.CenterCheckBox.Value = true;
                else
                    app.CenterCheckBox.Value = false;
                end
                
                % trigger mode
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'TriggerMode');
                switch currentIndex
                    case 0
                        app.TriggermodeDropDown.Value = 'Internal';
                    case 1
                        app.TriggermodeDropDown.Value = 'External Level Transition';
                    case 2
                        app.TriggermodeDropDown.Value = 'External Start';
                    case 3
                        app.TriggermodeDropDown.Value = 'External Exposure';
                    case 4
                        app.TriggermodeDropDown.Value = 'Software';
                    case 5
                        app.TriggermodeDropDown.Value = 'Advanced';
                    case 6
                        app.TriggermodeDropDown.Value = 'External';
                end
                
                % readout
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'PixelReadoutRate');                 % 0-10 MHz, 1-100 MHz, 2-200 MHz, 3-270 MHz
                switch currentIndex
                    case 0
                        app.ReadoutmodeDropDown.Value = '10 MHz';
                    case 1
                        app.ReadoutmodeDropDown.Value = '100 MHz';
                    case 2
                        app.ReadoutmodeDropDown.Value = '200 MHz';
                    case 3
                        app.ReadoutmodeDropDown.Value = '280 MHz';
                end
                %                 [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SensorReadoutMode');                % 0-Top Down Simultaneous, 1-Centre Out Simultaneous, 2-Outside In Simultaneous, 3-Bottom Up Simultaneous, 4-Top Down Sequential, 5-Bottom Up Sequential
                
                % gain mode
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');          % 0-12-bit (high well capacity), 1-12-bit (low noise), 2-16-bit (low noise & high well capacity)
                switch currentIndex
                    case 0
                        app.GainmodeDropDown.Value = '11-bit (high well capacity)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono12');
                    case 1
                        app.GainmodeDropDown.Value = '11-bit (low noise)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono12');
                    case 2
                        app.GainmodeDropDown.Value = '16-bit (low noise & high well capacity)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono16');
                end
                
                
                % ROI
                %                 [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
                [~,height] = AT_GetInt(app.hndl,'AOIHeight');
                app.HeightEditField.Value = height;
                [~,width] = AT_GetInt(app.hndl,'AOIWidth');
                app.WidthEditField.Value = width;
                %                 [~,left] = AT_GetInt(app.hndl,'AOILeft');
                %                 [~,stride] = AT_GetInt(app.hndl,'AOIStride');
                
                % exposure time
                ExposureTimeInSecond = app.ExposuretimeEditField.Value/1000;
                AT_SetFloat(app.hndl,'ExposureTime', ExposureTimeInSecond);
                pause(0.1)
                [~,ExposureTimeInSecond] = AT_GetFloat(app.hndl,'ExposureTime');
                app.ExposuretimeEditField.Value = ExposureTimeInSecond*1000;
                
                % fps
                [~,frameRate_Max] = AT_GetFloatMax(app.hndl,'FrameRate');
                AT_SetFloat(app.hndl,'FrameRate',frameRate_Max);
                pause(0.1)
                [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
                app.FramepersecondEditField.Value=frameRate;
                
                % open a new window
                app.hFig = figure('CloseRequestFcn','');
                width = app.WidthEditField.Value;
                height = app.HeightEditField.Value;
                app.hImage = imshow(zeros(height,width),'Border','Tight');
                set(gca,'DataAspectRatio',[1,1,1],'PlotBoxAspectRatio',[1,1,1])
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Connect Andor Neo.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                app.PreviewSwitch.Enable = false;
                app.CamConnectButton.Text = 'Connect';
                app.CamConnectButton.BackgroundColor = [0,1,0];
                app.PreviewSwitch.Enable = false;
                app.UpdateButton.Enable = false;
                app.TemperatureEditField.Enable = false;
                app.FramepersecondEditField.Enable = false;
                app.ExposuretimeEditField.Enable = false;
                app.GainmodeDropDown.Enable = false;
                app.TriggermodeDropDown.Enable = false;
                app.ReadoutmodeDropDown.Enable = false;
                app.WidthEditField.Enable = false;
                app.HeightEditField.Enable = false;
                app.SpuriousNoiseFilterCheckBox.Enable = false;
                app.OverlapCheckBox.Enable = false;
                app.CenterCheckBox.Enable = false;
                delete(app.hFig)
                
                % turn on/off cooling
                AT_SetBool(app.hndl,'SensorCooling',0);
                
                % close and clear camera session
                AT_Close(app.hndl);
                AT_FinaliseLibrary();
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Disconnect Andor Neo.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: PreviewSwitch
        function PreviewSwitchValueChanged(app, event)
            value = app.PreviewSwitch.Value;
            subvalue = app.UpdateButton.Enable;
            History = flip(app.HistoryTextArea.Value,1);
            if strcmp(value,'On') && strcmp(subvalue,'off')
                app.StreamLamp.Color = [0,1,0];
                app.CamConnectButton.Enable = false;
                app.StageConnectButton.Enable = false;
                app.BFConnectButton.Enable = false;
                app.ExposuretimeEditField.Enable = false;
                app.GainmodeDropDown.Enable = false;
                app.TriggermodeDropDown.Enable = false;
                app.ReadoutmodeDropDown.Enable = false;
                app.WidthEditField.Enable = false;
                app.HeightEditField.Enable = false;
                app.SpuriousNoiseFilterCheckBox.Enable = false;
                app.OverlapCheckBox.Enable = false;
                app.CenterCheckBox.Enable = false;
                app.XYZUpdateCheckBox.Enable = false;
                if (app.StageConnectButton.Value==1)
                    app.XYZUpdateCheckBox.Value = true;
                end
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Preview...'));
                app.HistoryTextArea.Value = flip(History,1);
                
                % check whether turn on white light
                if strcmp(app.BFSwitch.Value,'Off') && (app.BFConnectButton.Value==1)
                    app.BFSwitch.Value = 'On';
                    app.BFLamp.Color = [0,1,0];
                    
                    % write command to daq
                    write(app.dq,5);
                    
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn on the BF.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
                
                % camera setting
                [~,SensorTemp] = AT_GetFloat(app.hndl,'SensorTemperature');                     % get sensor temperaure
                app.TemperatureEditField.Value = SensorTemp;
                
                AT_SetEnumString(app.hndl,'CycleMode','Continuous');
                pause(0.1)
                
                AT_SetEnumString(app.hndl,'TriggerMode','Software');
                pause(0.1)
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'TriggerMode');
                switch currentIndex
                    case 0
                        app.TriggermodeDropDown.Value = 'Internal';
                    case 1
                        app.TriggermodeDropDown.Value = 'External Level Transition';
                    case 2
                        app.TriggermodeDropDown.Value = 'External Start';
                    case 3
                        app.TriggermodeDropDown.Value = 'External Exposure';
                    case 4
                        app.TriggermodeDropDown.Value = 'Software';
                    case 5
                        app.TriggermodeDropDown.Value = 'Advanced';
                    case 6
                        app.TriggermodeDropDown.Value = 'External';
                end
                
                value = app.GainmodeDropDown.Value;
                AT_SetEnumString(app.hndl,'SimplePreAmpGainControl',value);
                pause(0.1)
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');          % 0-12-bit (high well capacity), 1-12-bit (low noise), 2-16-bit (low noise & high well capacity)
                switch currentIndex
                    case 0
                        app.GainmodeDropDown.Value = '11-bit (high well capacity)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono12');
                    case 1
                        app.GainmodeDropDown.Value = '11-bit (low noise)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono12');
                    case 2
                        app.GainmodeDropDown.Value = '16-bit (low noise & high well capacity)';
                        AT_SetEnumString(app.hndl,'PixelEncoding','Mono16');
                end
                pause(0.1)
                
                [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
                app.FramepersecondEditField.Value = frameRate;
                
                % image flow
                width = app.WidthEditField.Value;
                height = app.HeightEditField.Value;
                [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
                [~,stride] = AT_GetInt(app.hndl,'AOIStride');
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');
                AT_Command(app.hndl,'AcquisitionStart');
                while (1)
                    AT_QueueBuffer(app.hndl,imagesize);
                    AT_Command(app.hndl,'SoftwareTrigger');
                    [~,buf] = AT_WaitBuffer(app.hndl,1000);
                    if (currentIndex<2)
                        [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                    else
                        [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                    end
                    

%                     set(app.hImage,'CData',flip(buf2.',2))
                    figure(10), cla(gca), imshow(flip(transpose(buf2)),[])
%                     set(gca, 'CLim',[min(buf2(:)),max(buf2(:))])
                    drawnow
                    
                    % update stage information
                    if (app.StageConnectButton.Value==1)
                        x = app.mmc.getXPosition();
                        y = app.mmc.getYPosition();
                        z = app.mmc.getPosition();
                        pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
                        if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                            app.PFSLamp.Color = [0,1,0];
                            app.PFSSwitch.Value = 'On';
                        else
                            app.PFSLamp.Color = [0.9,0.9,0.9];
                            app.PFSSwitch.Value = 'Off';
                        end
                        app.XEditField.Value = x;
                        app.YEditField.Value = y;
                        app.ZEditField.Value = z;
                        app.PFSEditField.Value = pfs_z;
                        %                         pause(0.05)
                    end
                    
                    if ~strcmp(app.PreviewSwitch.Value,'On')
                        AT_Command(app.hndl,'AcquisitionStop');
                        pause(0.1)
                        AT_Flush(app.hndl);
                        pause(0.1)
                        break;
                    end
                end
            else
                app.StreamLamp.Color = [0.9,0.9,0.9];
                app.CamConnectButton.Enable = true;
                app.StageConnectButton.Enable = true;
                app.BFConnectButton.Enable = true;
                app.ExposuretimeEditField.Enable = true;
                app.GainmodeDropDown.Enable = true;
                app.TriggermodeDropDown.Enable = true;
                app.ReadoutmodeDropDown.Enable = true;
                app.WidthEditField.Enable = true;
                app.HeightEditField.Enable = true;
                app.SpuriousNoiseFilterCheckBox.Enable = true;
                app.OverlapCheckBox.Enable = true;
                app.CenterCheckBox.Enable = true;
                app.XYZUpdateCheckBox.Enable = true;
                app.XYZUpdateCheckBox.Value = false;
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Stop.'));
                app.HistoryTextArea.Value = flip(History,1);
                
                % turn off white light
                if strcmp(app.BFSwitch.Value,'On') && (app.BFConnectButton.Value==1)
                    app.BFSwitch.Value = 'Off';
                    app.BFLamp.Color = [0.9,0.9,0.9];
                    
                    % write command to daq
                    write(app.dq,0);
                    
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn on the BF.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
            end
        end

        % Value changed function: StageConnectButton
        function StageConnectButtonValueChanged(app, event)
            value = app.StageConnectButton.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if (value==1)
                app.StageConnectButton.Text = 'Disconnect';
                app.StageConnectButton.BackgroundColor = [1,0,0];
                app.PFSSwitch.Enable = true;
                app.AddButton.Enable = true;
                app.XYZUpdateCheckBox.Enable = true;
                app.XYZUpdateCheckBox.Value = true;
                
                % create java library
                import mmcorej.*
                app.mmc = CMMCore;
                app.mmc.loadSystemConfiguration('MMConfig_TiE_XYZ.cfg');
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Connect Nikon motorized stage.'));
                app.HistoryTextArea.Value = flip(History,1);
                
                % check PFS state
                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                    app.PFSSwitch.Value = 'On';
                    app.PFSLamp.Color = [0,1,0];
                    
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Keep PFS on.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
            else
                app.StageConnectButton.Text = 'Connect';
                app.StageConnectButton.BackgroundColor = [0,1,0];
                app.PFSSwitch.Enable = false;
                app.AddButton.Enable = false;
                app.XYZUpdateCheckBox.Enable = false;
                app.XYZUpdateCheckBox.Value = false;
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Disonnect Nikon motorized stage.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
            
            %
            while (app.XYZUpdateCheckBox.Value==true) && (app.StartAcquiButton.Enable==false)
                x = app.mmc.getXPosition();
                y = app.mmc.getYPosition();
                z = app.mmc.getPosition();
                pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                    app.PFSLamp.Color = [0,1,0];
                    app.PFSSwitch.Value = 'On';
                else
                    app.PFSLamp.Color = [0.9,0.9,0.9];
                    app.PFSSwitch.Value = 'Off';
                end
                app.XEditField.Value = x;
                app.YEditField.Value = y;
                app.ZEditField.Value = z;
                app.PFSEditField.Value = pfs_z;
                pause(0.05)
            end
            
        end

        % Value changed function: PFSSwitch
        function PFSSwitchValueChanged(app, event)
            value = app.PFSSwitch.Value;
            History = flip(app.HistoryTextArea.Value,1);
            if strcmp(value,'On')
                app.PFSLamp.Color = [0,1,0];
                app.mmc.setProperty('TIPFSStatus','State','On');
                pause(0.5)
                
                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'Off')
                    app.PFSLamp.Color = [0.9,.9,0.9];
                    app.PFSSwitch.Value = 'Off';
                    
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Out of PFS.'));
                    app.HistoryTextArea.Value = flip(History,1);
                else
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn on PFS.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
                
                
            else
                app.PFSLamp.Color = [0.9,0.9,0.9];
                app.mmc.setProperty('TIPFSStatus','State','Off');
                pause(0.5)
                
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Turn off PFS.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: SpuriousNoiseFilterCheckBox
        function SpuriousNoiseFilterCheckBoxValueChanged(app, event)
            %             value = app.SpuriousNoiseFilterCheckBox.Value
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Use spurious noise filter.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: CenterCheckBox
        function CenterCheckBoxValueChanged(app, event)
            %             value = app.CenterCheckBox.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Center ROI.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: OverlapCheckBox
        function OverlapCheckBoxValueChanged(app, event)
            %             value = app.OverlapCheckBox.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Use overlap.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % reset preview axes
            delete(app.hFig);
            app.hFig = figure('CloseRequestFcn','');
            width = app.WidthEditField.Value;
            height = app.HeightEditField.Value;
            app.hImage = imshow(zeros(height,width),'Border','Tight');
            set(gca,'DataAspectRatio',[1,1,1],'PlotBoxAspectRatio',[1,1,1])
            
            % CCD temperature
            app.UpdateButton.Enable = false;
            app.PreviewSwitch.Enable = true;
            [~,SensorTemp] = AT_GetFloat(app.hndl,'SensorTemperature');                     % get sensor temperaure
            app.TemperatureEditField.Value = SensorTemp;
            
            % Center ROI
            if app.CenterCheckBox.Value==true
                AT_SetBool(app.hndl,'VerticallyCentreAOI',1);
            else
                AT_SetBool(app.hndl,'VerticallyCentreAOI',0);
            end
            [~,bool] = AT_GetBool(app.hndl,'VerticallyCentreAOI');
            if bool==1
                app.CenterCheckBox.Value = true;
            else
                app.CenterCheckBox.Value = false;
            end
            
            % Overlap
            if app.OverlapCheckBox.Value==true
                AT_SetBool(app.hndl,'Overlap',1);
            else
                AT_SetBool(app.hndl,'Overlap',0);
            end
            [~,bool] = AT_GetBool(app.hndl,'Overlap');
            if bool==1
                app.OverlapCheckBox.Value = true;
            else
                app.OverlapCheckBox.Value = false;
            end
            
            % SpuriousNoiseFilter
            if app.SpuriousNoiseFilterCheckBox.Value==true
                AT_SetBool(app.hndl,'SpuriousNoiseFilter',1);
            else
                AT_SetBool(app.hndl,'SpuriousNoiseFilter',0);
            end
            [~,bool] = AT_GetBool(app.hndl,'SpuriousNoiseFilter');
            if bool==1
                app.SpuriousNoiseFilterCheckBox.Value = true;
            else
                app.SpuriousNoiseFilterCheckBox.Value = false;
            end
            
            % ROI
            width = app.WidthEditField.Value;
            AT_SetInt(app.hndl,'AOIWidth',width);
            height = app.HeightEditField.Value;
            AT_SetInt(app.hndl,'AOIHeight',height);
            pause(0.1)
            [~,height] = AT_GetInt(app.hndl,'AOIHeight');
            app.HeightEditField.Value = height;
            [~,width] = AT_GetInt(app.hndl,'AOIWidth');
            app.WidthEditField.Value = width;
            %             [~,left] = AT_GetInt(app.hndl,'AOILeft');
            %             [~,stride] = AT_GetInt(app.hndl,'AOIStride');
            
            % gain mode
            value = app.GainmodeDropDown.Value;
            AT_SetEnumString(app.hndl,'SimplePreAmpGainControl',value);
            pause(0.1)
            [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');          % 0-11-bit (high well capacity), 1-11-bit (low noise), 2-16-bit (low noise & high well capacity)
            switch currentIndex
                case 0
                    app.GainmodeDropDown.Value = '11-bit (high well capacity)';
                case 1
                    app.GainmodeDropDown.Value = '11-bit (low noise)';
                case 2
                    app.GainmodeDropDown.Value = '16-bit (low noise & high well capacity)';
            end
            
            % trigger mode
            value = app.TriggermodeDropDown.Value;
            AT_SetEnumString(app.hndl,'TriggerMode',value);
            pause(0.1)
            [~,currentIndex] = AT_GetEnumIndex(app.hndl,'TriggerMode');
            switch currentIndex
                case 0
                    app.TriggermodeDropDown.Value = 'Internal';
                case 1
                    app.TriggermodeDropDown.Value = 'External Level Transition';
                case 2
                    app.TriggermodeDropDown.Value = 'External Start';
                case 3
                    app.TriggermodeDropDown.Value = 'External Exposure';
                case 4
                    app.TriggermodeDropDown.Value = 'Software';
                case 5
                    app.TriggermodeDropDown.Value = 'Advanced';
                case 6
                    app.TriggermodeDropDown.Value = 'External';
            end
            AT_SetEnumString(app.hndl,'CycleMode','Fixed');
            
            % readout
            value = app.ReadoutmodeDropDown.Value;
            AT_SetEnumString(app.hndl,'PixelReadoutRate',value);
            pause(0.1)
            [~,currentIndex] = AT_GetEnumIndex(app.hndl,'PixelReadoutRate');                 % 0-10 MHz, 1-100 MHz, 2-200 MHz, 3-270 MHz
            switch currentIndex
                case 0
                    app.ReadoutmodeDropDown.Value = '10 MHz';
                case 1
                    app.ReadoutmodeDropDown.Value = '100 MHz';
                case 2
                    app.ReadoutmodeDropDown.Value = '200 MHz';
                case 3
                    app.ReadoutmodeDropDown.Value = '280 MHz';
            end
            
            % exposure time
            ExposureTimeInSecond = app.ExposuretimeEditField.Value/1000;
            AT_SetFloat(app.hndl,'ExposureTime', ExposureTimeInSecond);
            pause(0.1)
            [~,ExposureTimeInSecond] = AT_GetFloat(app.hndl,'ExposureTime');
            app.ExposuretimeEditField.Value = ExposureTimeInSecond*1000;
            
            % fps
            [~,frameRate_Max] = AT_GetFloatMax(app.hndl,'FrameRate');
            AT_SetFloat(app.hndl,'FrameRate',frameRate_Max);
            pause(0.1)
            [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
            app.FramepersecondEditField.Value=frameRate;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Update camera setting.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: ExposuretimeEditField
        function ExposuretimeEditFieldValueChanged(app, event)
            value = app.ExposuretimeEditField.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',num2str(value),'" msec as exposure time.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',num2str(value),'" pixels as width.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',num2str(value),'" pixels as height.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: GainmodeDropDown
        function GainmodeDropDownValueChanged(app, event)
            value = app.GainmodeDropDown.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',value,'" as gain mode.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: TriggermodeDropDown
        function TriggermodeDropDownValueChanged(app, event)
            value = app.TriggermodeDropDown.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',value,'" as trigger mode.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: ReadoutmodeDropDown
        function ReadoutmodeDropDownValueChanged(app, event)
            value = app.ReadoutmodeDropDown.Value;
            app.UpdateButton.Enable = true;
            app.PreviewSwitch.Enable = false;
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Set "',value,'" as readout rate.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Button pushed function: ListButton
        function ListButtonPushed(app, event)
            dataselection(app);
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Open the recorded position list.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            x = app.mmc.getXPosition();
            y = app.mmc.getYPosition();
            z = app.mmc.getPosition();
            pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
            app.listing_pos = [app.listing_pos;x,y,z,pfs_z];
            app.roiCountEditField.Value = size(app.listing_pos,1);
            
            History = flip(app.HistoryTextArea.Value,1);
            nHistorys = size(History,1);
            History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Add ROI ', num2str(size(app.listing_pos,1)),'.'));
            app.HistoryTextArea.Value = flip(History,1);
        end

        % Value changed function: nTimersEditField
        function nTimersEditFieldValueChanged(app, event)
            value = app.nTimersEditField.Value;
            app.nTimerDropDown.Items = {};
            app.schedule = [];
            if (value>0)
                app.nTimerDropDown.Visible = true;
                Items{1} = 'Choose...';
                for i = 2:value+1
                    Items{i} = strcat('Timer-',num2str(i-1));
                    app.schedule(i-1,:) = [0,0];
                end
                app.nTimerDropDown.Items = Items;
            else
                app.nTimerDropDown.Visible = false;
            end
        end

        % Value changed function: nTimerDropDown
        function nTimerDropDownValueChanged(app, event)
            value = app.nTimerDropDown.Value;
            for i = 1:app.nTimersEditField.Value
                if strcmp(strcat('Timer-',num2str(i)),value)
                    nTimer = i;
                end
            end
            
            prompt = {'Enter interval time [sec]:','Enter the number of loop:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {num2str(app.schedule(nTimer,1)),num2str(app.schedule(nTimer,2))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                app.schedule(nTimer,:) = [str2double(answer{1}),str2double(answer{2})];
            end
            app.nLoopsEditField.Value = sum(app.schedule(:,2));
            app.schedule
            app.nTimerDropDown.Value = 'Choose...';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            checksum = sum(app.schedule(:,1).*app.schedule(:,2)==0);
            checkupdate = app.UpdateButton.Enable;
            
            if checkupdate==1
                app.StartAcquiButton.Enable = 0;
                errordlg('Please update camera setting.','Reminder.')
            else
                if checksum>0
                    History = flip(app.HistoryTextArea.Value,1);
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Timer setting occured an error.'));
                    app.HistoryTextArea.Value = flip(History,1);
                else
                    app.StartAcquiButton.Enable = true;
                    for i = 1:size(app.schedule,1)
                        IntervalTimeInSecond = app.schedule(i,1);
                        app.schedule_timer.timers(i) = timer('TimerFcn',@(~,~) disp(datetime(now,'ConvertFrom','datenum')),...
                            'StartDelay',IntervalTimeInSecond);
                    end
                    app.schedule_timer.timers
                    
                    % allocate buffer
                    [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
                    nFrames = app.nFramesEditField.Value;
                    AT_SetInt(app.hndl,'FrameCount',nFrames);
                    for X = 1:nFrames
                        AT_QueueBuffer(app.hndl,imagesize);
                    end
                    
                    History = flip(app.HistoryTextArea.Value,1);
                    nHistorys = size(History,1);
                    History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Ready to start.'));
                    app.HistoryTextArea.Value = flip(History,1);
                end
            end
        end

        % Button pushed function: StartAcquiButton
        function StartAcquiButtonPushed(app, event)
            checksum = 0;
            value = app.BFSwitch.Value;
            if strcmp(value,'On')
                errordlg('Please turn off the white light.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            value = app.PreviewSwitch.Value;
            if strcmp(value,'On')
                errordlg('Please stop previewing.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            outputdir = app.FolderpathEditField.Value;
            if isempty(outputdir)
                errordlg('Please choose the working folder.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            value = app.TriggermodeDropDown.Value;
            if ~strcmp(value,'Internal')
                errordlg('Please use "Internal" mode as trigger mode.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            value = app.OverlapCheckBox.Value;
            if value==0
                errordlg('Please use "Overlap" mode.','Reminder.')
                app.StartAcquiButton.Enable = false;
            else
                checksum = checksum +1;
            end
            
            if (checksum==5)
                app.StartAcquiButton.Enable = false;
                app.nFramesEditField.Enable = false;
                app.nLoopsEditField.Enable = false;
                app.nTimersEditField.Enable = false;
                app.nTimerDropDown.Enable = false;
                app.XYZUpdateCheckBox.Enable = false;
                app.XYZUpdateCheckBox.Value = false;
                app.ChooseButton.Enable = false;
                app.BFConnectButton.Enable = false;
                app.BFSwitch.Enable = false;
                app.StageConnectButton.Enable = false;
                app.ListButton.Enable = false;
                app.AddButton.Enable = false;
                app.PFSSwitch.Enable = false;
                app.CamConnectButton.Enable = false;
                app.PreviewSwitch.Enable = false;
                app.ExposuretimeEditField.Enable = false;
                app.GainmodeDropDown.Enable = false;
                app.ReadoutmodeDropDown.Enable = false;
                app.TriggermodeDropDown.Enable = false;
                app.SpuriousNoiseFilterCheckBox.Enable = false;
                app.OverlapCheckBox.Enable = false;
                app.WidthEditField.Enable = false;
                app.HeightEditField.Enable = false;
                app.CenterCheckBox.Enable = false;
                app.ApplyButton.Enable = false;
                app.CorrectXYCheckBox.Enable = false;
                app.CorrectZCheckBox.Enable = false;
                
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Start...'));
                app.HistoryTextArea.Value = flip(History,1);
                
                nLoops = sum(app.schedule(:,2));
                ntimer = 1;
                nLoops_limit = app.schedule(ntimer,2);
                nPoss = size(app.listing_pos,1);
                
                for nPos = 1:nPoss
                    outputsubdir = [outputdir,'\Pos',num2str(nPos)];
                    mkdir(outputsubdir);
                end
                
                TF_CorrectXY = app.CorrectXYCheckBox.Value;
                TF_CorrectZ = app.CorrectZCheckBox.Value;
                CorrectXY_map = zeros(nPoss,nLoops,3);
                CorrectZ_map = zeros(nPoss,nLoops,2);
                nFrames = app.nFramesEditField.Value;
                height = app.HeightEditField.Value;
                width = app.WidthEditField.Value;
                [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
                [~,stride] = AT_GetInt(app.hndl,'AOIStride');
                [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');
                database = uint16(zeros(height,width,nFrames));
                [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
                disp(['The acquisition rate is ',num2str(frameRate),' Hz.'])
                disp(strcat('Running...',string(datetime(now,'ConvertFrom','datenum'))));
                for nLoop = 1:nLoops
                    if nLoop<nLoops_limit
                        T = app.schedule_timer.timers(ntimer);
                    elseif nLoop==nLoops
                        T = app.schedule_timer.timers(ntimer);
                    else
                        ntimer=ntimer+1;
                        nLoops_limit = nLoops_limit+app.schedule(ntimer,2);
                        T = app.schedule_timer.timers(ntimer);
                    end
                    start(T)
                    
                    % turn on the white light
                    write(app.dq,5);
                    app.BFLamp.Color = [0,1,0];
                    pause(0.1)
                    
                    disp(nLoop)
                    for nPos = 1:nPoss
                        app.StreamLamp.Color = [0,1,0];
                        
                        % move stage
                        app.mmc.setXYPosition(app.listing_pos(nPos,1),app.listing_pos(nPos,2));
                        if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'Off')
                            app.mmc.setPosition(app.listing_pos(nPos,3));
                        else
                            app.mmc.setProperty('TIPFSOffset','Position',num2str(app.listing_pos(nPos,4)));
                        end
                        pause(15)
                        
                        % XY correction
                        if (TF_CorrectXY==1)
                            if (nLoop>1)
                                if (currentIndex<2)
                                    AT_Command(app.hndl,'AcquisitionStart');
                                    for i = 0:nFrames-1
                                        [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                        AT_QueueBuffer(app.hndl,imagesize);
                                        [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                                        database(:,:,i+1) = flip(transpose(buf2),2);
                                    end
                                    AT_Command(app.hndl,'AcquisitionStop');
                                else
                                    AT_Command(app.hndl,'AcquisitionStart');
                                    for i = 0:nFrames-1
                                        [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                        AT_QueueBuffer(app.hndl,imagesize);
                                        [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                                        database(:,:,i+1) = flip(transpose(buf2),2);
                                    end
                                    AT_Command(app.hndl,'AcquisitionStop');
                                end
                                database1 = mean(database(height/2-128:height/2+127,width/2-128:width/2+127,:),3);
                                [shiftY,shiftX] = driftXY_FFTCorr(app.database0(:,:,nPos),database1);
                                if (abs((shiftX-65)*6.5/60) <=7) && (abs((shiftY-65)*6.5/60) <=7) % 7um limitation
                                    correct_pos_x = app.mmc.getXPosition()+(shiftX-65)*6.5/60;
                                    correct_pos_y = app.mmc.getYPosition()-(shiftY-65)*6.5/60;
                                    app.mmc.setXYPosition(correct_pos_x,correct_pos_y);
                                    pause(0.5)
                                    app.listing_pos(nPos,1) = correct_pos_x;
                                    app.listing_pos(nPos,2) = correct_pos_y;
                                    CorrectXY_map(nPos,nLoop,1) = 1;
                                    CorrectXY_map(nPos,nLoop,2) = (shiftX-65)*6.5/60;
                                    CorrectXY_map(nPos,nLoop,3) = -(shiftY-65)*6.5/60;
                                else
                                    CorrectXY_map(nPos,nLoop,1) = nan;
                                end
                            end
                        end
                        
                        % z correction
                        if (TF_CorrectZ==1)
                            if (nLoop>1)
                                target = Z_targets(:,:,nPos);
                                target = (target-mean2(target))/std2(target);
                                C = [];
                                if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                                    for zstep = -8:8
                                        z = app.listing_pos(nPos,4) + zstep*0.5;
                                        app.mmc.setProperty('TIPFSOffset','Position',num2str(z));
                                        if (zstep==-8)
                                            pause(5)
                                        else
                                            pause(1.5)
                                        end
                                        
                                        if (currentIndex<2)
                                            AT_Command(app.hndl,'AcquisitionStart');
                                            for i = 0:nFrames-1
                                                [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                                AT_QueueBuffer(app.hndl,imagesize);
                                                [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                                                database(:,:,i+1) = flip(transpose(buf2),2);
                                            end
                                            AT_Command(app.hndl,'AcquisitionStop');
                                        else
                                            AT_Command(app.hndl,'AcquisitionStart');
                                            for i = 0:nFrames-1
                                                [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                                AT_QueueBuffer(app.hndl,imagesize);
                                                [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                                                database(:,:,i+1) = flip(transpose(buf2),2);
                                            end
                                            AT_Command(app.hndl,'AcquisitionStop');
                                        end
                                        
                                        suspect = double(database(:,:,end));
                                        suspect = (suspect-mean2(suspect))/std2(suspect);
                                        C = [C,mean2(target.*suspect)];
                                    end
                                end
                                
                                dz = (-8:8)*0.5;
                                z = app.listing_pos(nPos,4) + dz(C==max(C));
                                app.mmc.setProperty('TIPFSOffset','Position',num2str(z));
                                app.listing_pos(nPos,4) = z;
                                CorrectZ_map(nPos,nLoop,1) = 1;
                                CorrectZ_map(nPos,nLoop,2) = dz(C==max(C));
                                pause(5)
                            end
                        end
                        
                        
                        % kinectic series
                        if (currentIndex<2)
                            AT_Command(app.hndl,'AcquisitionStart');
                            for i = 0:nFrames-1
                                [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                AT_QueueBuffer(app.hndl,imagesize);
                                [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                                database(:,:,i+1) = flip(transpose(buf2),2);
                            end
                            AT_Command(app.hndl,'AcquisitionStop');
                        else
                            AT_Command(app.hndl,'AcquisitionStart');
                            for i = 0:nFrames-1
                                [~,buf] = AT_WaitBuffer(app.hndl,1000);
                                AT_QueueBuffer(app.hndl,imagesize);
                                [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                                database(:,:,i+1) = flip(transpose(buf2),2);
                            end
                            AT_Command(app.hndl,'AcquisitionStop');
                        end
                        app.StreamLamp.Color = [0.9,0.9,0.9];
                        
                        % write database into disk
                        outputfile = [outputdir,'\Pos',num2str(nPos),'\T_',num2str(nLoop-1),'.tif'];
                        for nFrame = 1:nFrames
                            imwrite(database(:,:,nFrame),outputfile,'WriteMode','append','Compression','None');
                        end
                        
                        % do target pattern
                        if (TF_CorrectXY==1)
                            if (nLoop==1)
                                app.database0(:,:,nPos) = zeros(256,256);
                                database00 = mean(database(height/2-64:height/2+63,width/2-64:width/2+63,:),3);
                                database00 = database00-mean2(database00);
                                database00 = database00/std2(database00);
                                app.database0(1:128,1:128,nPos) = database00;
                            end
                        end
                        
                        if (TF_CorrectZ==1)
                            if (nLoop==1)
                                Z_targets(:,:,nPos) = double(database(:,:,end));
                            end
                        end
                    end
                    
                    % turn off the white light
                    write(app.dq,0);
                    app.BFLamp.Color = [0.9,0.9,0.9];
                    pause(0.1)
                    
                    if nLoop<nLoops
                        wait(T)
                    else
                        stop(T)
                        delete(T)
                    end
                end
                AT_Flush(app.hndl);
                
                % save correction xy drift
                if (TF_CorrectXY==1)
                    outputfile = [outputdir,'\CorrectXY_map.mat'];
                    save(outputfile,'CorrectXY_map');
                end
                
                if (TF_CorrectZ==1)
                    outputfile = [outputdir,'\CorrectZ_map.mat'];
                    save(outputfile,'CorrectZ_map');
                end
                
                app.nFramesEditField.Enable = true;
                app.nLoopsEditField.Enable = true;
                app.nTimersEditField.Enable = true;
                app.nTimerDropDown.Enable = true;
                app.XYZUpdateCheckBox.Enable = true;
                app.ChooseButton.Enable = true;
                app.BFConnectButton.Enable = true;
                app.BFSwitch.Enable = true;
                app.StageConnectButton.Enable = true;
                app.ListButton.Enable = true;
                app.AddButton.Enable = true;
                app.PFSSwitch.Enable = true;
                app.CamConnectButton.Enable = true;
                app.PreviewSwitch.Enable = true;
                app.ExposuretimeEditField.Enable = true;
                app.GainmodeDropDown.Enable = true;
                app.ReadoutmodeDropDown.Enable = true;
                app.TriggermodeDropDown.Enable = true;
                app.SpuriousNoiseFilterCheckBox.Enable = true;
                app.OverlapCheckBox.Enable = true;
                app.WidthEditField.Enable = true;
                app.HeightEditField.Enable = true;
                app.CenterCheckBox.Enable = true;
                app.ApplyButton.Enable = true;
                app.CorrectXYCheckBox.Enable = true;
                app.CorrectZCheckBox.Enable = true;
                
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Done.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Value changed function: XYZUpdateCheckBox
        function XYZUpdateCheckBoxValueChanged(app, event)
            value = app.XYZUpdateCheckBox.Value;
            while (value==true)
                if (app.XYZUpdateCheckBox.Value==false)
                    break;
                else
                    x = app.mmc.getXPosition();
                    y = app.mmc.getYPosition();
                    z = app.mmc.getPosition();
                    pfs_z = str2double(app.mmc.getProperty('TIPFSOffset','Position'));
                    if strcmp(app.mmc.getProperty('TIPFSStatus','State'),'On')
                        app.PFSLamp.Color = [0,1,0];
                        app.PFSSwitch.Value = 'On';
                    else
                        app.PFSLamp.Color = [0.9,0.9,0.9];
                        app.PFSSwitch.Value = 'Off';
                    end
                    app.XEditField.Value = x;
                    app.YEditField.Value = y;
                    app.ZEditField.Value = z;
                    app.PFSEditField.Value = pfs_z;
                    pause(0.05)
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            checksum = 0;
            if app.BFConnectButton.Value==1
                errordlg('Please disconnet the white light.','Reminder.')
            else
                checksum = checksum+1;
            end
            
            if app.CamConnectButton.Value==1
                errordlg('Please disconnect camera.','Reminder.')
            else
                checksum = checksum+1;
            end
            
            if app.StageConnectButton.Value==1
                errordlg('Please disconnect stage.','Reminder.')
            else
                checksum = checksum+1;
            end
            
            if checksum==3
                delete(app)
            end
            
        end

        % Value changed function: CorrectXYCheckBox
        function CorrectXYCheckBoxValueChanged(app, event)
            value = app.CorrectXYCheckBox.Value;
            if value ==1
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Correct drifting XY.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Not to correct drifting XY.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
            
        end

        % Value changed function: CorrectZCheckBox
        function CorrectZCheckBoxValueChanged(app, event)
            value = app.CorrectZCheckBox.Value;
            if value ==1
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Lock z plane.'));
                app.HistoryTextArea.Value = flip(History,1);
            else
                History = flip(app.HistoryTextArea.Value,1);
                nHistorys = size(History,1);
                History{nHistorys+1,1} = char(strcat(string(datetime(now,'ConvertFrom','datenum')),' -- Unlock z plane.'));
                app.HistoryTextArea.Value = flip(History,1);
            end
        end

        % Button pushed function: Start2Button
        function Start2ButtonPushed(app, event)
            % allocate buffer
            [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
            nFrames = 1;
            AT_SetInt(app.hndl,'FrameCount',nFrames);
            for X = 1:nFrames
                AT_QueueBuffer(app.hndl,imagesize);
            end
            
            % 
            outputdir = app.FolderpathEditField.Value;
            height = app.HeightEditField.Value;
            width = app.WidthEditField.Value;
            x1 = app.listing_pos(1,1);
            y1 = app.listing_pos(1,2);
            x2 = app.listing_pos(2,1);
            y2 = app.listing_pos(2,2);
            nxs = round(abs((x2-x1)/(width*(6.5/100))))+1
            nys = round(abs((y2-y1)/(height*(6.5/100))))+1
            nPos = 0;
            if x2-x1>0
                x_direction = 1;
            else
                x_direction = -1;
            end
            
            if y2-y1>0
                y_direction = 1;
            else
                y_direction = -1;
            end
            
            %
            [~,imagesize] = AT_GetInt(app.hndl,'ImageSizeBytes');
            [~,stride] = AT_GetInt(app.hndl,'AOIStride');
            [~,currentIndex] = AT_GetEnumIndex(app.hndl,'SimplePreAmpGainControl');
            database = uint16(zeros(height,width,nFrames));
            [~,frameRate] = AT_GetFloat(app.hndl,'FrameRate');
            disp(['The acquisition rate is ',num2str(frameRate),' Hz.'])
            disp(strcat('Running...',string(datetime(now,'ConvertFrom','datenum'))));

            %
            % turn on the white light
            write(app.dq,5);
            app.BFLamp.Color = [0,1,0];
            pause(0.1)

            for nx = 1:nxs
                
                if nx>1
                    x = x + x_direction*width*(6.5/100);
                else
                    x = x1;
                    y = y1;
                end
                
                for ny = 1:nys
                    nPos = nPos+1;
                    if ny>1
                        y = y + y_direction*height*(6.5/100);
                    end
                    
                    % move stage
                    app.mmc.setXYPosition(x,y);
                    pause(0.5)
                    
                    % kinectic series
                    if (currentIndex<2)
                        AT_Command(app.hndl,'AcquisitionStart');
                        for i = 0:nFrames-1
                            [~,buf] = AT_WaitBuffer(app.hndl,1000);
                            AT_QueueBuffer(app.hndl,imagesize);
                            [~,buf2] = AT_ConvertMono12ToMatrix(buf,height,width,stride);
                            database(:,:,i+1) = flip(transpose(buf2),2);
                        end
                        AT_Command(app.hndl,'AcquisitionStop');
                    else
                        AT_Command(app.hndl,'AcquisitionStart');
                        for i = 0:nFrames-1
                            [~,buf] = AT_WaitBuffer(app.hndl,1000);
                            AT_QueueBuffer(app.hndl,imagesize);
                            [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);
                            database(:,:,i+1) = flip(transpose(buf2),2);
                        end
                        AT_Command(app.hndl,'AcquisitionStop');
                    end
                    app.StreamLamp.Color = [0.9,0.9,0.9];
                    
                    % write database into disk
                    outputfile = [outputdir,'\XY_',num2str(nPos),'.tif'];
                    imwrite(database,outputfile,'Compression','None');
                end
                y_direction = -y_direction;
            end
            
            % turn off the white light
            write(app.dq,0);
            app.BFLamp.Color = [0.9,0.9,0.9];
            pause(0.1)
            AT_Flush(app.hndl);
            
            disp('Done.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SavefiletoPanel = uipanel(app.UIFigure);
            app.SavefiletoPanel.Title = 'Save file to...';
            app.ChooseButton = uibutton(app.SavefiletoPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.OpenButton = uibutton(app.SavefiletoPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.FolderpathEditFieldLabel = uilabel(app.SavefiletoPanel);
            app.FolderpathEditFieldLabel.Text = 'Folder path:';
            app.FolderpathEditField = uieditfield(app.SavefiletoPanel, 'text');
            app.WhitelightPanel = uipanel(app.UIFigure);
            app.WhitelightPanel.Title = 'White light';
            app.BFSwitch = uiswitch(app.WhitelightPanel, 'rocker');
            app.BFSwitch.ValueChangedFcn = createCallbackFcn(app, @BFSwitchValueChanged, true);
            app.BFSwitch.Enable = 'off';
            app.BFLampLabel = uilabel(app.WhitelightPanel);
            app.BFLampLabel.Text = 'BF';
            app.BFLamp = uilamp(app.WhitelightPanel);
            app.BFConnectButton = uibutton(app.WhitelightPanel, 'state');
            app.BFConnectButton.ValueChangedFcn = createCallbackFcn(app, @BFConnectButtonValueChanged, true);
            app.BFConnectButton.Text = 'Connect';
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Camera';
            app.StreamLampLabel = uilabel(app.CameraPanel);
            app.StreamLampLabel.Text = 'Stream';
            app.StreamLamp = uilamp(app.CameraPanel);
            app.PreviewSwitch = uiswitch(app.CameraPanel, 'slider');
            app.PreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @PreviewSwitchValueChanged, true);
            app.PreviewSwitch.Enable = 'off';
            app.PreviewSwitch.Tooltip = {'Preview?'};
            app.CamConnectButton = uibutton(app.CameraPanel, 'state');
            app.CamConnectButton.ValueChangedFcn = createCallbackFcn(app, @CamConnectButtonValueChanged, true);
            app.CamConnectButton.Text = 'Connect';
            app.ExposuretimeEditFieldLabel = uilabel(app.CameraPanel);
            app.ExposuretimeEditFieldLabel.Text = 'Exposure time';
            app.ExposuretimeEditField = uieditfield(app.CameraPanel, 'numeric');
            app.ExposuretimeEditField.ValueDisplayFormat = '%.3f';
            app.ExposuretimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuretimeEditFieldValueChanged, true);
            app.ExposuretimeEditField.Enable = 'off';
            app.ExposuretimeEditField.Tooltip = {'The unit is msec.'};
            app.ExposuretimeEditField.Value = 30;
            app.GainmodeDropDownLabel = uilabel(app.CameraPanel);
            app.GainmodeDropDownLabel.Text = 'Gain mode';
            app.GainmodeDropDown = uidropdown(app.CameraPanel);
            app.GainmodeDropDown.Items = {'11-bit (high well capacity)', '11-bit (low noise)', '16-bit (low noise & high well capacity)'};
            app.GainmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @GainmodeDropDownValueChanged, true);
            app.GainmodeDropDown.Enable = 'off';
            app.GainmodeDropDown.Value = '11-bit (high well capacity)';
            app.TriggermodeDropDownLabel = uilabel(app.CameraPanel);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.TriggermodeDropDown = uidropdown(app.CameraPanel);
            app.TriggermodeDropDown.Items = {'Internal', 'External Level Transition', 'External Start', 'External Exposure', 'Software', 'Advanced', 'External'};
            app.TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggermodeDropDownValueChanged, true);
            app.TriggermodeDropDown.Enable = 'off';
            app.TriggermodeDropDown.Value = 'Internal';
            app.SpuriousNoiseFilterCheckBox = uicheckbox(app.CameraPanel);
            app.SpuriousNoiseFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpuriousNoiseFilterCheckBoxValueChanged, true);
            app.SpuriousNoiseFilterCheckBox.Enable = 'off';
            app.SpuriousNoiseFilterCheckBox.Text = 'SpuriousNoiseFilter';
            app.SpuriousNoiseFilterCheckBox.Value = true;
            app.TemperatureEditFieldLabel = uilabel(app.CameraPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.CameraPanel, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%.2f';
            app.TemperatureEditField.Editable = 'off';
            app.TemperatureEditField.Enable = 'off';
            app.TemperatureEditField.Tooltip = {'The unit is degree Celsius.'};
            app.FramepersecondEditFieldLabel = uilabel(app.CameraPanel);
            app.FramepersecondEditFieldLabel.Text = 'Frame per second';
            app.FramepersecondEditField = uieditfield(app.CameraPanel, 'numeric');
            app.FramepersecondEditField.Editable = 'off';
            app.FramepersecondEditField.Enable = 'off';
            app.ReadoutmodeDropDownLabel = uilabel(app.CameraPanel);
            app.ReadoutmodeDropDownLabel.Text = 'Readout mode';
            app.ReadoutmodeDropDown = uidropdown(app.CameraPanel);
            app.ReadoutmodeDropDown.Items = {'10 MHz', '100 MHz', '200 MHz', '280 MHz'};
            app.ReadoutmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @ReadoutmodeDropDownValueChanged, true);
            app.ReadoutmodeDropDown.Enable = 'off';
            app.ReadoutmodeDropDown.Value = '10 MHz';
            app.CenterCheckBox = uicheckbox(app.CameraPanel);
            app.CenterCheckBox.ValueChangedFcn = createCallbackFcn(app, @CenterCheckBoxValueChanged, true);
            app.CenterCheckBox.Enable = 'off';
            app.CenterCheckBox.Text = 'Center';
            app.CenterCheckBox.Value = true;
            app.WidthEditFieldLabel = uilabel(app.CameraPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.CameraPanel, 'numeric');
            app.WidthEditField.ValueDisplayFormat = '%.0f';
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Enable = 'off';
            app.WidthEditField.Value = 2560;
            app.HeightEditFieldLabel = uilabel(app.CameraPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.CameraPanel, 'numeric');
            app.HeightEditField.ValueDisplayFormat = '%.0f';
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Enable = 'off';
            app.HeightEditField.Value = 2160;
            app.UpdateButton = uibutton(app.CameraPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.OverlapCheckBox = uicheckbox(app.CameraPanel);
            app.OverlapCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlapCheckBoxValueChanged, true);
            app.OverlapCheckBox.Enable = 'off';
            app.OverlapCheckBox.Text = 'Overlap';
            app.OverlapCheckBox.Value = true;
            app.MotorizedStagePanel = uipanel(app.UIFigure);
            app.MotorizedStagePanel.Title = 'Motorized Stage';
            app.StageConnectButton = uibutton(app.MotorizedStagePanel, 'state');
            app.StageConnectButton.ValueChangedFcn = createCallbackFcn(app, @StageConnectButtonValueChanged, true);
            app.StageConnectButton.Text = 'Connect';
            app.XEditFieldLabel = uilabel(app.MotorizedStagePanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.2f';
            app.XEditField.Editable = 'off';
            app.XEditField.Tooltip = {'The unit is micron.'};
            app.YEditFieldLabel = uilabel(app.MotorizedStagePanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.2f';
            app.YEditField.Editable = 'off';
            app.YEditField.Tooltip = {'The unit is micron.'};
            app.ZEditFieldLabel = uilabel(app.MotorizedStagePanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.2f';
            app.ZEditField.Editable = 'off';
            app.ZEditField.Tooltip = {'The unit is micron.'};
            app.PFSEditFieldLabel = uilabel(app.MotorizedStagePanel);
            app.PFSEditFieldLabel.Text = 'PFS';
            app.PFSEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.PFSEditField.ValueDisplayFormat = '%.2f';
            app.PFSEditField.Editable = 'off';
            app.AddButton = uibutton(app.MotorizedStagePanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Tooltip = {'Add a region of interest.'};
            app.AddButton.Text = 'Add';
            app.PFSLampLabel = uilabel(app.MotorizedStagePanel);
            app.PFSLampLabel.Text = 'PFS';
            app.PFSLamp = uilamp(app.MotorizedStagePanel);
            app.PFSSwitch = uiswitch(app.MotorizedStagePanel, 'slider');
            app.PFSSwitch.ValueChangedFcn = createCallbackFcn(app, @PFSSwitchValueChanged, true);
            app.PFSSwitch.Enable = 'off';
            app.ListButton = uibutton(app.MotorizedStagePanel, 'push');
            app.ListButton.ButtonPushedFcn = createCallbackFcn(app, @ListButtonPushed, true);
            app.ListButton.Tooltip = {'Show information of the  selected ROI.'};
            app.ListButton.Text = 'List';
            app.roiCountEditField = uieditfield(app.MotorizedStagePanel, 'numeric');
            app.roiCountEditField.ValueDisplayFormat = '%.0f';
            app.roiCountEditField.Editable = 'off';
            app.roiCountEditField.Tooltip = {'The number of selected ROI.'};
            app.XYZUpdateCheckBox = uicheckbox(app.MotorizedStagePanel);
            app.XYZUpdateCheckBox.ValueChangedFcn = createCallbackFcn(app, @XYZUpdateCheckBoxValueChanged, true);
            app.XYZUpdateCheckBox.Enable = 'off';
            app.XYZUpdateCheckBox.Tooltip = {'Renew XYZ'};
            app.XYZUpdateCheckBox.Text = '';
            app.AcquisitionsettingPanel = uipanel(app.UIFigure);
            app.AcquisitionsettingPanel.Title = 'Acquisition setting';
            app.StartAcquiButton = uibutton(app.AcquisitionsettingPanel, 'push');
            app.StartAcquiButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquiButtonPushed, true);
            app.StartAcquiButton.Enable = 'off';
            app.StartAcquiButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.AcquisitionsettingPanel);
            app.StarvationTab = uitab(app.TabGroup);
            app.StarvationTab.Title = 'Starvation';
            app.nFramesEditFieldLabel = uilabel(app.StarvationTab);
            app.nFramesEditFieldLabel.Text = 'nFrames';
            app.nFramesEditField = uieditfield(app.StarvationTab, 'numeric');
            app.nFramesEditField.ValueDisplayFormat = '%.0f';
            app.nFramesEditField.Value = 100;
            app.nLoopsEditFieldLabel = uilabel(app.StarvationTab);
            app.nLoopsEditFieldLabel.Text = 'nLoops';
            app.nLoopsEditField = uieditfield(app.StarvationTab, 'numeric');
            app.nLoopsEditField.Editable = 'off';
            app.nLoopsEditField.Tooltip = {''};
            app.nTimerDropDown = uidropdown(app.StarvationTab);
            app.nTimerDropDown.Items = {};
            app.nTimerDropDown.ValueChangedFcn = createCallbackFcn(app, @nTimerDropDownValueChanged, true);
            app.nTimerDropDown.Visible = 'off';
            app.nTimerDropDown.Tooltip = {''};
            app.nTimerDropDown.Value = {};
            app.nTimersEditFieldLabel = uilabel(app.StarvationTab);
            app.nTimersEditFieldLabel.Text = 'nTimers';
            app.nTimersEditField = uieditfield(app.StarvationTab, 'numeric');
            app.nTimersEditField.ValueDisplayFormat = '%.0f';
            app.nTimersEditField.ValueChangedFcn = createCallbackFcn(app, @nTimersEditFieldValueChanged, true);
            app.nTimersEditField.Tooltip = {'The number of timer.'};
            app.CorrectXYCheckBox = uicheckbox(app.StarvationTab);
            app.CorrectXYCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectXYCheckBoxValueChanged, true);
            app.CorrectXYCheckBox.Tooltip = {'Correct drifting XY to  increase stage stability.'};
            app.CorrectXYCheckBox.Text = 'Correct XY?';
            app.CorrectZCheckBox = uicheckbox(app.StarvationTab);
            app.CorrectZCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectZCheckBoxValueChanged, true);
            app.CorrectZCheckBox.Text = 'Correct Z?';
            app.LargeXYTab = uitab(app.TabGroup);
            app.LargeXYTab.Title = 'LargeXY';
            app.Start2Button = uibutton(app.LargeXYTab, 'push');
            app.Start2Button.ButtonPushedFcn = createCallbackFcn(app, @Start2ButtonPushed, true);
            app.Start2Button.Text = 'Start2';
            app.ApplyButton = uibutton(app.AcquisitionsettingPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.HistoryPanel = uipanel(app.UIFigure);
            app.HistoryPanel.Title = 'History';
            app.HistoryTextArea = uitextarea(app.HistoryPanel);
            app.HistoryTextArea.Value = {'Have a nice day!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NikonTiE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end